classdef setCutoffs < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SliderLabel                     matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        HyperpolarizationMinEditFieldLabel  matlab.ui.control.Label
        HyperpolarizationMinEditField   matlab.ui.control.NumericEditField
        HyperpolarizationMaxEditFieldLabel  matlab.ui.control.Label
        HyperpolarizationMaxEditField   matlab.ui.control.NumericEditField
        DepolarizationMinEditFieldLabel  matlab.ui.control.Label
        DepolarizationMinEditField      matlab.ui.control.NumericEditField
        DepolarizationMaxEditFieldLabel  matlab.ui.control.Label
        DepolarizationMaxEditField      matlab.ui.control.NumericEditField
        ApplyButton                     matlab.ui.control.Button
        AdvanceButton                   matlab.ui.control.Button
        EdgeSizeEditFieldLabel          matlab.ui.control.Label
        EdgeSizeEditField               matlab.ui.control.NumericEditField
        NumberofHyperpolarizedEpochsLabel  matlab.ui.control.Label
        numHyperpolarizedValue          matlab.ui.control.Label
        NumberofDepolarizedEpochsLabel  matlab.ui.control.Label
        numDepolarizedValue             matlab.ui.control.Label
        NumberofUnclassifiedEpochsLabel  matlab.ui.control.Label
        numUnclassifiedValue            matlab.ui.control.Label
        VmHistogram                     matlab.ui.control.UIAxes
        VmPlot                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Vms %Membrane potentials for all epochs
        Hmin %Hyperpolarization minimum cutoff
        Hmax %Hyperpolarization maximum cutoff
        Dmin %Depolarization minimum cutoff
        Dmax %Depolarization maximum cutoff
        HVms %Membrane Potentials from Hyperpolarized trials
        DVms %Membrane Potentials from Depolarized Trials
        UnclassifiedVms % Vms that haven't been assigned to either hyperpolarization or depolarization categories
    end
    
    methods (Access = private)
        
        function results = ShowCutoffResults(app)
            %Displays the results of the latest cutoff selections if
            %they're valid
            
            %check that results make sense first
            if app.Hmin > app.Hmax || app.Hmax > app.Dmin || app.Dmin > app.Dmax
                return
            end
            
            %histogram
            cla(app.VmHistogram);
            edgs = [min(app.Vms)+0.1*min(app.Vms):app.EdgeSizeEditField.Value:max(app.Vms)-0.1*max(app.Vms)];
            hold(app.VmHistogram, 'on')
            histogram(app.VmHistogram, app.HVms, 'FaceColor', 'b', 'BinEdges', edgs)
            histogram(app.VmHistogram, app.DVms, 'FaceColor', 'g', 'BinEdges', edgs)
            histogram(app.VmHistogram, app.UnclassifiedVms, 'FaceColor', [0.3, 0.3, 0.3], 'BinEdges', edgs)
            hold(app.VmHistogram, 'off')
            
            %line plot
            cla(app.VmPlot);
            zerothread = zeros(1, numel(app.Vms));
            xvals = 1:numel(zerothread);
            hold(app.VmPlot, 'on')
            plot(app.VmPlot, xvals, app.Vms, 'k')
            plot(app.VmPlot, xvals, zerothread+app.Hmin, '--b')
            plot(app.VmPlot, xvals, zerothread+app.Hmax, '--b')
            plot(app.VmPlot, xvals, zerothread+app.Dmin, '--g')
            plot(app.VmPlot, xvals, zerothread+app.Dmax, '--g')
            hold(app.VmPlot, 'off')
            app.VmPlot.YLim = [min(app.Vms)*1.1, max(app.Vms)*0.9];
            app.numHyperpolarizedValue.Text = num2str(numel(app.HVms));
            app.numDepolarizedValue.Text = num2str(numel(app.DVms));
            app.numUnclassifiedValue.Text = num2str(numel(app.UnclassifiedVms));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Vms, varargin)
            app.Vms = Vms;
            
            if nargin == 3 %varargin 1 is the previously saved cuttoff values
                %use previously saved values as the initial guess if they
                %were entered
                oldValues = varargin{1};
                app.Hmin = oldValues(1);
                app.Hmax = oldValues(2);
                app.Dmin = oldValues(3);
                app.Dmax = oldValues(4);
            else
                %make initial guess at cutoff values
                app.Hmin = min(Vms);
                app.Hmax = median(Vms);
                app.Dmin = median(Vms);
                app.Dmax = max(Vms);
            end
            
            app.HyperpolarizationMinEditField.Value = app.Hmin;
            app.HyperpolarizationMaxEditField.Value = app.Hmax;
            app.DepolarizationMinEditField.Value = app.Dmin;
            app.DepolarizationMaxEditField.Value = app.Dmax;
            
            app.Slider.Limits = [app.Hmin, app.Dmax];
            app.Slider.Value = median(Vms);
            
            app.SortAndPostEpochs;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            if app.Hmin > app.Hmax || app.Hmax > app.Dmin || app.Dmin > app.Dmax
                warndlg('Input values are not in correct order, enter new values')
                return
            end
            app.ShowCutoffResults;
        end

        % Value changed function: DepolarizationMaxEditField, 
        % DepolarizationMinEditField, EdgeSizeEditField, 
        % HyperpolarizationMaxEditField, 
        % HyperpolarizationMinEditField
        function SortAndPostEpochs(app, event)
            %assign epochs to HVms and DVms. Then Draw the data
            app.Hmin = app.HyperpolarizationMinEditField.Value;
            app.Hmax = app.HyperpolarizationMaxEditField.Value;
            app.Dmin = app.DepolarizationMinEditField.Value;
            app.Dmax = app.DepolarizationMaxEditField.Value;
            
            app.HVms = app.Vms(app.Vms >= app.Hmin & app.Vms <= app.Hmax);
            app.DVms = app.Vms(app.Vms >= app.Dmin & app.Vms <= app.Dmax);
            app.UnclassifiedVms = app.Vms(app.Vms < app.Hmin| (app.Vms >app.Hmax & app.Vms < app.Dmin) | app.Vms > app.Dmax);
            
            app.ShowCutoffResults; %this is what actually draws/posts the results to the UI
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
            app.HyperpolarizationMaxEditField.Value = value;
            app.DepolarizationMinEditField.Value = value;
            
            app.SortAndPostEpochs;
        end

        % Button pushed function: AdvanceButton
        function AdvanceButtonPushed(app, event)
            assignin('base', 'cutoffs', [app.Hmin, app.Hmax, app.Dmin, app.Dmax]) %send data to the workspace, the close the window
            closereq();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 30;
            app.HyperpolarizationMinEditFieldLabel = uilabel(app.UIFigure);
            app.HyperpolarizationMinEditFieldLabel.Text = 'Hyperpolarization Min';
            app.HyperpolarizationMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.HyperpolarizationMinEditField.ValueChangedFcn = createCallbackFcn(app, @SortAndPostEpochs, true);
            app.HyperpolarizationMaxEditFieldLabel = uilabel(app.UIFigure);
            app.HyperpolarizationMaxEditFieldLabel.Text = 'Hyperpolarization Max';
            app.HyperpolarizationMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.HyperpolarizationMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SortAndPostEpochs, true);
            app.DepolarizationMinEditFieldLabel = uilabel(app.UIFigure);
            app.DepolarizationMinEditFieldLabel.Text = 'Depolarization Min';
            app.DepolarizationMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepolarizationMinEditField.ValueChangedFcn = createCallbackFcn(app, @SortAndPostEpochs, true);
            app.DepolarizationMaxEditFieldLabel = uilabel(app.UIFigure);
            app.DepolarizationMaxEditFieldLabel.Text = 'Depolarization Max';
            app.DepolarizationMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.DepolarizationMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SortAndPostEpochs, true);
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.AdvanceButton = uibutton(app.UIFigure, 'push');
            app.AdvanceButton.ButtonPushedFcn = createCallbackFcn(app, @AdvanceButtonPushed, true);
            app.AdvanceButton.Text = 'Advance';
            app.EdgeSizeEditFieldLabel = uilabel(app.UIFigure);
            app.EdgeSizeEditFieldLabel.Text = 'Edge Size';
            app.EdgeSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.EdgeSizeEditField.ValueChangedFcn = createCallbackFcn(app, @SortAndPostEpochs, true);
            app.EdgeSizeEditField.Value = 2;
            app.NumberofHyperpolarizedEpochsLabel = uilabel(app.UIFigure);
            app.NumberofHyperpolarizedEpochsLabel.Text = 'Number of Hyperpolarized Epochs:';
            app.numHyperpolarizedValue = uilabel(app.UIFigure);
            app.numHyperpolarizedValue.Text = '0';
            app.NumberofDepolarizedEpochsLabel = uilabel(app.UIFigure);
            app.NumberofDepolarizedEpochsLabel.Text = 'Number of Depolarized Epochs:';
            app.numDepolarizedValue = uilabel(app.UIFigure);
            app.numDepolarizedValue.Text = '0';
            app.NumberofUnclassifiedEpochsLabel = uilabel(app.UIFigure);
            app.NumberofUnclassifiedEpochsLabel.Text = 'Number of Unclassified Epochs:';
            app.numUnclassifiedValue = uilabel(app.UIFigure);
            app.numUnclassifiedValue.Text = '0';
            app.VmHistogram = uiaxes(app.UIFigure);
            title(app.VmHistogram, 'All Vms')
            xlabel(app.VmHistogram, 'Vm')
            ylabel(app.VmHistogram, 'Count')
            zlabel(app.VmHistogram, 'Z')
            app.VmPlot = uiaxes(app.UIFigure);
            title(app.VmPlot, 'Vm By Epoch')
            xlabel(app.VmPlot, 'Epoch Number')
            ylabel(app.VmPlot, 'Vm')
            zlabel(app.VmPlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = setCutoffs(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end