classdef batchAnalyses_OKR_Physiology < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        FlipIncludedCellsMenu         matlab.ui.container.Menu
        IterativeInclusionsMenu       matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        CopytoClipboardMenu           matlab.ui.container.Menu
        SendtoWorkspaceMenu           matlab.ui.container.Menu
        PriorityMenu                  matlab.ui.container.Menu
        SetIncludeExcludePriorityOrderMenu  matlab.ui.container.Menu
        SetAnalysisPriorityOrderMenu  matlab.ui.container.Menu
        RunExcludeFirstMenu           matlab.ui.container.Menu
        PreselectedMenu               matlab.ui.container.Menu
        UPCellsMenu                   matlab.ui.container.Menu
        DOWNCellsMenu                 matlab.ui.container.Menu
        UpSpikesMenu                  matlab.ui.container.Menu
        DownSpikesMenu                matlab.ui.container.Menu
        AllUsableDSMenu               matlab.ui.container.Menu
        VentralCellsMenu              matlab.ui.container.Menu
        DorsalCellsMenu               matlab.ui.container.Menu
        SpecificFiltersMenu           matlab.ui.container.Menu
        ExcludeRetinasWhereOneCellTypeWasLabeledMenu  matlab.ui.container.Menu
        FilterButton                  matlab.ui.control.Button
        AnalysisListBoxLabel          matlab.ui.control.Label
        AnalysisListBox_Include       matlab.ui.control.ListBox
        MustContainLabel              matlab.ui.control.Label
        List_Include                  matlab.ui.control.TextArea
        TagsListBoxLabel              matlab.ui.control.Label
        TagsListBox_Include           matlab.ui.control.ListBox
        minangleEditFieldLabel        matlab.ui.control.Label
        minangleEditField_Include     matlab.ui.control.NumericEditField
        maxangleEditFieldLabel        matlab.ui.control.Label
        maxangleEditField_Include     matlab.ui.control.NumericEditField
        PreferredDirectionCheckBox_Include  matlab.ui.control.CheckBox
        DirectionSelectivityIndexCheckBox_Include  matlab.ui.control.CheckBox
        minDSIEditFieldLabel          matlab.ui.control.Label
        minDSIEditField_Include       matlab.ui.control.NumericEditField
        IncludeLabel                  matlab.ui.control.Label
        PreferredDirectionCheckBox_Exclude  matlab.ui.control.CheckBox
        DirectionSelectivityIndexCheckBox_Exclude  matlab.ui.control.CheckBox
        ExcludeLabel                  matlab.ui.control.Label
        CannotContainLabel            matlab.ui.control.Label
        List_Exclude                  matlab.ui.control.TextArea
        TagsListBox_2Label            matlab.ui.control.Label
        TagsListBox_Exclude           matlab.ui.control.ListBox
        minangleEditField_2Label      matlab.ui.control.Label
        minangleEditField_Exclude     matlab.ui.control.NumericEditField
        maxangleEditField_2Label      matlab.ui.control.Label
        maxangleEditField_Exclude     matlab.ui.control.NumericEditField
        minDSIEditField_2Label        matlab.ui.control.Label
        minDSIEditField_Exclude       matlab.ui.control.NumericEditField
        WillAnalyzeListBoxLabel       matlab.ui.control.Label
        WillAnalyzeListBox            matlab.ui.control.ListBox
        TagSwitch_Include             matlab.ui.control.Switch
        TagSwitch_Exclude             matlab.ui.control.Switch
        TotalCellsLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        cellList % Description
        cutCellList %names and structures of included cells
        cellNames %names of cells that have been included for analysis
        priorityOrderIncludeExclude_1 = {'Moving_Bar'; 'Moving_Grating_Direction'}; %first field name should be one of these. Priority is in given order
        priorityOrderIncludeExclude_2 = {'_ff'; 'SP10'; 'Extracellular'}; %child field name should be one of these. Priority is in given order
        
    end
    
    methods (Access = private)
        
        function cutCellList = includeCells(app, cellList)
            cutCellList = {};
            tags = app.TagsListBox_Include.Value;
            tagSwitch = app.TagSwitch_Include.Value;
            for i = 1:size(cellList, 1)
                skip = 0;
                cell_i = cellList{i, 1};
                struct_i = cellList{i, 2};
                
                %tags
                cell_iTags = struct_i.Tags;
                if strcmp(tagSwitch, '&&')
                    for j = 1:numel(tags)
                        if strcmp(tags{j}, 'None')
                            skip = 0;
                            break
                        elseif ismember(tags{j}, cell_iTags)
                            continue
                        else
                            skip = 1; %don't break in case 'None' comes later
                        end
                    end
                elseif strcmp(tagSwitch, '||')
                    skip = 1;
                    for j = 1:numel(tags)
                        if ismember(tags{j}, cell_iTags) || strcmp(tags{j}, 'None')
                            skip = 0; %good to go once you've found the first tag listed
                            break
                        end
                    end
                end
                
                %dsi and preferred direction
                if app.DirectionSelectivityIndexCheckBox_Include.Value == 1 ...
                        || app.PreferredDirectionCheckBox_Include.Value == 1
                minDSI = app.minDSIEditField_Include.Value;
                analyses = fieldnames(struct_i.Analyses_Completed);
                analysisToUse_1 = findAnalysisToUse(app.priorityOrderIncludeExclude_1 , analyses, [], {'Width', 'Speed'});
                if ~strcmp(analysisToUse_1, 'NoneFound')
                    analysisToUse_2 = findAnalysisToUse(app.priorityOrderIncludeExclude_2, fieldnames(struct_i.Analyses_Completed.(analysisToUse_1)),{'Extracellular'}, []);
                else
                    analysisToUse_2 = 'NoneFound';
                end
                
                if strcmp(analysisToUse_2, 'NoneFound')
                    continue %skip cell
                end
                dsi = struct_i.Analyses_Completed.(analysisToUse_1).(analysisToUse_2).Analysis_Results.DSI;
                pd = struct_i.Analyses_Completed.(analysisToUse_1).(analysisToUse_2).Analysis_Results.PreferredDirection;
                
                %dsi first
                if app.DirectionSelectivityIndexCheckBox_Include.Value == 1
                    if dsi < app.minDSIEditField_Include.Value
                        continue %skip cell
                    end
                end
                
                if app.PreferredDirectionCheckBox_Include.Value == 1
                    minAngle = app.minangleEditField_Include.Value;
                    maxAngle = app.maxangleEditField_Include.Value;
                    
                    
                    pd = mod(pd, 360); %convert to between 0 and 360
                    maxAngle = mod(maxAngle, 360);
                    minAngle = mod(minAngle, 360);
                    
                    %check edge case where max < mn
                    if maxAngle < minAngle
                        if pd < minAngle && pd > maxAngle
                            continue %skip cell
                        end
                    else %cases where min is less than max
                        if pd < minAngle || pd > maxAngle
                            continue
                        end
                    end
                end
                end
                requirements = split(strrep(app.List_Include.Value, ' ', ''), ',');
                for j = 1:numel(requirements)
                    if isempty(strfind(cell_i, requirements{j}))
                        skip = 1;
                        break
                    end
                end
                
                if skip
                    continue
                end
                
                cutCellList{end+1, 1} = cell_i;
                cutCellList{end, 2} = struct_i;
            end
        end


        %% Exclude cells
        function cutCellList = excludeCells(app, cellList)
            cutCellList = {};
            tags = app.TagsListBox_Exclude.Value;
            tagSwitch = app.TagSwitch_Exclude.Value;
            for i = 1:size(cellList, 1)
                skip = 0; %default is to keep any given cell
                cell_i = cellList{i, 1};
                struct_i = cellList{i, 2};
                
                %tags
                cell_iTags = struct_i.Tags;
                if strcmp(tagSwitch, '&&')
                    skip = 1;
                    for j = 1:numel(tags)
                        if ~ismember(tags{j}, cell_iTags)
                            skip = 0; %don't skip if one of the tags isn't listed for the cell
                            break
                        end
                    end
                elseif strcmp(tagSwitch, '||')
                    for j = 1:numel(tags)
                        if ismember(tags{j}, cell_iTags)
                            skip = 1; %if one tag is a member, you'll want to skip this cell
                            break
                        end
                    end
                end
                
                %dsi and preferred direction
                if app.DirectionSelectivityIndexCheckBox_Exclude.Value == 1 ...
                        || app.PreferredDirectionCheckBox_Exclude.Value == 1
                minDSI = app.minDSIEditField_Exclude.Value;
                analyses = fieldnames(struct_i.Analyses_Completed);
                analysisToUse_1 = findAnalysisToUse(app.priorityOrderIncludeExclude_1, analyses,[], []);
                if ~strcmp(analysisToUse_1, 'NoneFound')
                    analysisToUse_2 = findAnalysisToUse(app.priorityOrderIncludeExclude_2, fieldnames(struct_i.Analyses_Completed.(analysisToUse_1)), {'Extracellular'}, []);
                else
                    analysisToUse_2 = 'NoneFound';
                end
                
                if ~strcmp(analysisToUse_2, 'NoneFound') %none found won't necessarily skip the cell
                    dsi = struct_i.Analyses_Completed.(analysisToUse_1).(analysisToUse_2).Analysis_Results.DSI;
                    pd = struct_i.Analyses_Completed.(analysisToUse_1).(analysisToUse_2).Analysis_Results.PreferredDirection;
                    
                    %dsi first
                    if app.DirectionSelectivityIndexCheckBox_Exclude.Value == 1
                        if dsi > app.minDSIEditField_Exclude.Value
                            continue %skip cell
                        end
                    end
                    
                    if app.PreferredDirectionCheckBox_Exclude.Value == 1
                        minAngle = app.minangleEditField_Exclude.Value;
                        maxAngle = app.maxangleEditField_Exclude.Value;
                        
                        
                        pd = mod(pd, 360); %convert to between 0 and 360
                        maxAngle = mod(maxAngle, 360);
                        minAngle = mod(minAngle, 360);
                        
                        %check edge case where max < mn
                        %check edge case where max < mn
                        if maxAngle < minAngle
                            if ~(pd < minAngle && pd > maxAngle)
                                continue %skip cell
                            end
                        else %cases where min is less than max
                            if ~(pd < minAngle || pd > maxAngle)
                                continue
                            end
                        end
                    end
                end
                end
                faults = split(strrep(app.List_Exclude.Value, ' ', ''), ',');
                for j = 1:numel(faults)
                    if strfind(cell_i, faults{j})
                        skip = 1;
                        break
                    end
                end
                
                if skip
                    continue
                end
                
                cutCellList{end+1, 1} = cell_i;
                cutCellList{end, 2} = struct_i;
            end
        end
        
        
    end
    
    methods (Access = public)
        function sentOut = outsideCall(app, str, varargin)
            
            if strcmp(varargin {1}, 'ExcludeSingleLabels')
                app.ExcludeRetinasWhereOneCellTypeWasLabeledMenuSelected();
            end
            
            if strcmp(str, 'Ups - Spikes')
                app.UpSpikesMenuSelected;
                
            elseif strcmp(str, 'Downs - Spikes')
                app.DownSpikesMenuSelected;
                
            elseif strcmp(str, 'UP Cells')
                app.UPCellsMenuSelected;
            elseif strcmp(str, 'DOWN Cells')
                app.DOWNCellsMenuSelected;
            elseif strcmp(str, 'All Usable DS')
                app.AllUsableDSMenuSelected;
            elseif strcmp(str, 'Ventral Cells')
                app.VentralCellsMenuSelected;
            elseif strcmp(str, 'Dorsal Cells')
                app.DorsalCellsMenuSelected;
            end
            
            sentOut = app.cutCellList;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %load appropriate data
            load('C:\Users\mrsco\Box\DataAndAnalysis\labData\OKR\batch\cellList_OKR.mat', 'cellList');
            app.cellList = cellList;
        end

        % Value changed function: 
        % DirectionSelectivityIndexCheckBox_Include
        function DirectionSelectivityIndexCheckBox_IncludeValueChanged(app, event)
            value = app.DirectionSelectivityIndexCheckBox_Include.Value;
            app.minDSIEditField_Include.Enable = (app.minDSIEditField_Include.Enable-1)^2;
        end

        % Value changed function: 
        % DirectionSelectivityIndexCheckBox_Exclude
        function DirectionSelectivityIndexCheckBox_ExcludeValueChanged(app, event)
            value = app.DirectionSelectivityIndexCheckBox_Exclude.Value;
            app.minDSIEditField_Exclude.Enable = (app.minDSIEditField_Exclude.Enable-1)^2;
        end

        % Value changed function: PreferredDirectionCheckBox_Include
        function PreferredDirectionCheckBox_IncludeValueChanged(app, event)
            value = app.PreferredDirectionCheckBox_Include.Value;
            app.minangleEditField_Include.Enable = (app.minangleEditField_Include.Enable-1)^2;
            app.maxangleEditField_Include.Enable = (app.maxangleEditField_Include.Enable-1)^2;
        end

        % Value changed function: PreferredDirectionCheckBox_Exclude
        function PreferredDirectionCheckBox_ExcludeValueChanged(app, event)
            value = app.PreferredDirectionCheckBox_Exclude.Value;
            app.minangleEditField_Exclude.Enable = (app.minangleEditField_Exclude.Enable-1)^2;
            app.maxangleEditField_Exclude.Enable = (app.maxangleEditField_Exclude.Enable-1)^2;
        end

        % Menu selected function: RunExcludeFirstMenu
        function RunExcludeFirstMenuSelected(app, event)
            app.RunExcludeFirstMenu.Checked = (app.RunExcludeFirstMenu.Checked-1)^2;
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            if app.IterativeInclusionsMenu.Checked == 1
                cellList = app.cutCellList;
            else
                cellList = app.cellList;
            end
            
            
            if app.RunExcludeFirstMenu.Checked == 1
                cutCellList = app.excludeCells(cellList);
                cutCellList = app.includeCells(cutCellList);
            else
                cutCellList = app.includeCells(cellList);
                cutCellList = app.excludeCells(cutCellList);
            end
            
            
            app.cutCellList = cutCellList;
            if isempty(cutCellList)
                app.cellNames = {'No Cells Found'};
            else
                app.cellNames = cutCellList(:, 1);
            end
            
            app.TotalCellsLabel.Text = ['Total Cells: ' num2str(numel(app.cellNames))];
            app.WillAnalyzeListBox.Items = app.cellNames;
            app.WillAnalyzeListBox.Value = app.cellNames;
            
        end

        % Menu selected function: IterativeInclusionsMenu
        function IterativeInclusionsMenuSelected(app, event)
            app.IterativeInclusionsMenu.Checked = (app.IterativeInclusionsMenu.Checked - 1)^2;
        end

        % Menu selected function: FlipIncludedCellsMenu
        function FlipIncludedCellsMenuSelected(app, event)
            flipList = {};
            allCellNames = app.cellList(:, 1);
            for i = 1:numel(allCellNames)
                cell_i = allCellNames{i};
                if ~ismember(cell_i, app.cellNames)
                    struct_i = app.cellList{i, 2};
                    flipList{end+1, 1} = cell_i;
                    flipList{end, 2} = struct_i;
                end
            end
            app.cellNames = flipList(:, 1);
            app.cutCellList = flipList;
            
            app.TotalCellsLabel.Text = ['Total Cells: ' num2str(numel(app.cellNames))];
            app.WillAnalyzeListBox.Items = app.cellNames;
            app.WillAnalyzeListBox.Value = app.cellNames;
        end

        % Menu selected function: CopytoClipboardMenu
        function CopytoClipboardMenuSelected(app, event)
            %copies comma seperated string of cell names to the system clipboard
            if isempty(app.cellNames)
                warndlg('Nothing copied to clipboard. Run inclusion first')
            end
            copyString = [];
            cNames = string(app.cellNames);
            for i = 1:numel(cNames)
                copyString = strcat(copyString, cNames(i), ',');
            end
            
            clipboard('copy', copyString)
        end

        % Menu selected function: SendtoWorkspaceMenu
        function SendtoWorkspaceMenuSelected(app, event)
            assignin('base', 'IncludedCells', app.cutCellList)
        end

        % Menu selected function: UpSpikesMenu
        function UpSpikesMenuSelected(app, event)
            app.DirectionSelectivityIndexCheckBox_Include.Value = 1;
            app.minDSIEditField_Include.Value = 0.05;
            app.minDSIEditField_Include.Enable = 1;
            app.TagsListBox_Include.Value = 'MTN';
            app.PreferredDirectionCheckBox_Include.Value = 1;
            app.minangleEditField_Include.Value = 30;
            app.maxangleEditField_Include.Value = 150;
            app.minangleEditField_Include.Enable = 1;
            app.maxangleEditField_Include.Enable = 1;
            app.TagsListBox_Exclude.Value = {'Avoid For Tuning', 'Mislabeled'};
            app.TagSwitch_Exclude.Value = '||';
            app.FilterButtonPushed();
        end

        % Menu selected function: DownSpikesMenu
        function DownSpikesMenuSelected(app, event)
            app.DirectionSelectivityIndexCheckBox_Include.Value = 1;
            app.minDSIEditField_Include.Value = 0.05;
            app.minDSIEditField_Include.Enable = 1;
            app.TagsListBox_Include.Value = 'MTN';
            app.PreferredDirectionCheckBox_Include.Value = 1;
            app.PreferredDirectionCheckBox_Include.Enable = 1;
            app.minangleEditField_Include.Value = 210;
            app.maxangleEditField_Include.Value = 330;
            app.minangleEditField_Include.Enable = 1;
            app.maxangleEditField_Include.Enable = 1;
            app.TagsListBox_Exclude.Value = {'Avoid For Tuning', 'Mislabeled'};
            app.TagSwitch_Exclude.Value = '||';
            app.FilterButtonPushed();
        end

        % Menu selected function: AllUsableDSMenu
        function AllUsableDSMenuSelected(app, event)
            app.DirectionSelectivityIndexCheckBox_Include.Value = 1;
            app.minDSIEditField_Include.Value = 0.1;
            app.minDSIEditField_Include.Enable = 1;
            app.TagsListBox_Include.Value = 'MTN';
            app.TagsListBox_Exclude.Value = {'Avoid For Tuning', 'Mislabeled'};
            app.TagSwitch_Exclude.Value = '||';
            app.FilterButtonPushed();
        end

        % Menu selected function: 
        % ExcludeRetinasWhereOneCellTypeWasLabeledMenu
        function ExcludeRetinasWhereOneCellTypeWasLabeledMenuSelected(app, event)
            app.List_Exclude.Value = ['SHOKR33, SHOKR50, SHOKR53, SHOKR58, SHOKR62', 'SHOKR80'];
            app.FilterButtonPushed();
        end

        % Menu selected function: UPCellsMenu
        function UPCellsMenuSelected(app, event)
            app.TagsListBox_Include.Value = {'MTN', 'UP Cell'};
            app.FilterButtonPushed();
        end

        % Menu selected function: DOWNCellsMenu
        function DOWNCellsMenuSelected(app, event)
            app.TagsListBox_Include.Value = {'MTN', 'DOWN Cell'};
            app.FilterButtonPushed();
        end

        % Menu selected function: VentralCellsMenu
        function VentralCellsMenuSelected(app, event)
            %take all cells in ventral retina
            app.cutCellList = {};
            for i = 1:size(app.cellList, 1)
                cell_i = app.cellList{i, 1};
                struct_i = app.cellList{i, 2};
                
                %tags
                cell_iTags = struct_i.Tags;
                if ~ismember('MTN', cell_iTags)
                    continue
                end
                
                %check if cell has listed coordinates
                if ~isfield(struct_i, 'coordinates')
                    continue
                end
                if ~isfield(struct_i.coordinates, 'polar')
                    continue
                end
                
                coords = struct_i.coordinates.polar;
                
                angleDeg = mod(rad2deg(coords(1)), 360);
                if angleDeg >180 %cell is in ventral retina
                    app.cutCellList{end+1, 1} = cell_i;
                    app.cutCellList{end, 2} = struct_i;
                end
            end
            
            if isempty(app.cutCellList)
                app.cellNames = {'No Cells Found'};
            else
                app.cellNames = app.cutCellList(:, 1);
            end
            
            app.TotalCellsLabel.Text = ['Total Cells: ' num2str(numel(app.cellNames))];
            app.WillAnalyzeListBox.Items = app.cellNames;
            app.WillAnalyzeListBox.Value = app.cellNames;
        end

        % Menu selected function: DorsalCellsMenu
        function DorsalCellsMenuSelected(app, event)
            %take all cells in dorsal retina
            app.cutCellList = {};
            for i = 1:size(app.cellList, 1)
                cell_i = app.cellList{i, 1};
                struct_i = app.cellList{i, 2};
                
                %tags
                cell_iTags = struct_i.Tags;
                if ~ismember('MTN', cell_iTags)
                    continue
                end
                
                %check if cell has listed coordinates
                if ~isfield(struct_i, 'coordinates')
                    continue
                end
                if ~isfield(struct_i.coordinates, 'polar')
                    continue
                end
                coords = struct_i.coordinates.polar;
                
                angleDeg = mod(rad2deg(coords(1)), 360); %get angle in degrees
                if angleDeg < 180 %cell is in dorsal retina
                    app.cutCellList{end+1, 1} = cell_i;
                    app.cutCellList{end, 2} = struct_i;
                end
            end
            
            if isempty(app.cutCellList)
                app.cellNames = {'No Cells Found'};
            else
                app.cellNames = app.cutCellList(:, 1);
            end
            
            app.TotalCellsLabel.Text = ['Total Cells: ' num2str(numel(app.cellNames))];
            app.WillAnalyzeListBox.Items = app.cellNames;
            app.WillAnalyzeListBox.Value = app.cellNames;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.FlipIncludedCellsMenu = uimenu(app.FileMenu);
            app.FlipIncludedCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @FlipIncludedCellsMenuSelected, true);
            app.FlipIncludedCellsMenu.Text = 'Flip Included Cells';
            app.IterativeInclusionsMenu = uimenu(app.FileMenu);
            app.IterativeInclusionsMenu.MenuSelectedFcn = createCallbackFcn(app, @IterativeInclusionsMenuSelected, true);
            app.IterativeInclusionsMenu.Text = 'Iterative Inclusions';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.CopytoClipboardMenu = uimenu(app.EditMenu);
            app.CopytoClipboardMenu.MenuSelectedFcn = createCallbackFcn(app, @CopytoClipboardMenuSelected, true);
            app.CopytoClipboardMenu.Text = 'Copy to Clipboard';
            app.SendtoWorkspaceMenu = uimenu(app.EditMenu);
            app.SendtoWorkspaceMenu.MenuSelectedFcn = createCallbackFcn(app, @SendtoWorkspaceMenuSelected, true);
            app.SendtoWorkspaceMenu.Text = 'Send to Workspace';
            app.PriorityMenu = uimenu(app.UIFigure);
            app.PriorityMenu.Text = 'Priority';
            app.SetIncludeExcludePriorityOrderMenu = uimenu(app.PriorityMenu);
            app.SetIncludeExcludePriorityOrderMenu.Text = 'Set Include/Exclude Priority Order';
            app.SetAnalysisPriorityOrderMenu = uimenu(app.PriorityMenu);
            app.SetAnalysisPriorityOrderMenu.Text = 'Set Analysis Priority Order';
            app.RunExcludeFirstMenu = uimenu(app.PriorityMenu);
            app.RunExcludeFirstMenu.MenuSelectedFcn = createCallbackFcn(app, @RunExcludeFirstMenuSelected, true);
            app.RunExcludeFirstMenu.Text = 'Run Exclude First';
            app.PreselectedMenu = uimenu(app.UIFigure);
            app.PreselectedMenu.Text = 'Preselected';
            app.UPCellsMenu = uimenu(app.PreselectedMenu);
            app.UPCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @UPCellsMenuSelected, true);
            app.UPCellsMenu.Text = 'UP Cells';
            app.DOWNCellsMenu = uimenu(app.PreselectedMenu);
            app.DOWNCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @DOWNCellsMenuSelected, true);
            app.DOWNCellsMenu.Text = 'DOWN Cells';
            app.UpSpikesMenu = uimenu(app.PreselectedMenu);
            app.UpSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @UpSpikesMenuSelected, true);
            app.UpSpikesMenu.Text = 'Up - Spikes';
            app.DownSpikesMenu = uimenu(app.PreselectedMenu);
            app.DownSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @DownSpikesMenuSelected, true);
            app.DownSpikesMenu.Text = 'Down - Spikes';
            app.AllUsableDSMenu = uimenu(app.PreselectedMenu);
            app.AllUsableDSMenu.MenuSelectedFcn = createCallbackFcn(app, @AllUsableDSMenuSelected, true);
            app.AllUsableDSMenu.Text = 'All Usable DS';
            app.VentralCellsMenu = uimenu(app.PreselectedMenu);
            app.VentralCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @VentralCellsMenuSelected, true);
            app.VentralCellsMenu.Text = 'Ventral Cells';
            app.DorsalCellsMenu = uimenu(app.PreselectedMenu);
            app.DorsalCellsMenu.MenuSelectedFcn = createCallbackFcn(app, @DorsalCellsMenuSelected, true);
            app.DorsalCellsMenu.Text = 'Dorsal Cells';
            app.SpecificFiltersMenu = uimenu(app.PreselectedMenu);
            app.SpecificFiltersMenu.Text = 'Specific Filters';
            app.ExcludeRetinasWhereOneCellTypeWasLabeledMenu = uimenu(app.SpecificFiltersMenu);
            app.ExcludeRetinasWhereOneCellTypeWasLabeledMenu.MenuSelectedFcn = createCallbackFcn(app, @ExcludeRetinasWhereOneCellTypeWasLabeledMenuSelected, true);
            app.ExcludeRetinasWhereOneCellTypeWasLabeledMenu.Text = 'Exclude Retinas Where One Cell Type Was Labeled';
            app.FilterButton = uibutton(app.UIFigure, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.AnalysisListBoxLabel = uilabel(app.UIFigure);
            app.AnalysisListBoxLabel.Text = 'Analysis';
            app.AnalysisListBox_Include = uilistbox(app.UIFigure);
            app.AnalysisListBox_Include.Items = {'Item 1', 'Item 2', 'Item 3', 'Item 4', 'Item 5', ''};
            app.MustContainLabel = uilabel(app.UIFigure);
            app.MustContainLabel.Text = 'Must Contain';
            app.List_Include = uitextarea(app.UIFigure);
            app.List_Include.Value = {'c'};
            app.TagsListBoxLabel = uilabel(app.UIFigure);
            app.TagsListBoxLabel.Text = 'Tags';
            app.TagsListBox_Include = uilistbox(app.UIFigure);
            app.TagsListBox_Include.Items = {'MTN', 'UP Cell', 'DOWN Cell', 'Avoid For Tuning', 'Mislabeled', 'None'};
            app.TagsListBox_Include.Multiselect = 'on';
            app.TagsListBox_Include.Value = {'MTN'};
            app.minangleEditFieldLabel = uilabel(app.UIFigure);
            app.minangleEditFieldLabel.Text = 'min angle';
            app.minangleEditField_Include = uieditfield(app.UIFigure, 'numeric');
            app.minangleEditField_Include.Enable = 'off';
            app.maxangleEditFieldLabel = uilabel(app.UIFigure);
            app.maxangleEditFieldLabel.Text = 'max angle';
            app.maxangleEditField_Include = uieditfield(app.UIFigure, 'numeric');
            app.maxangleEditField_Include.Enable = 'off';
            app.PreferredDirectionCheckBox_Include = uicheckbox(app.UIFigure);
            app.PreferredDirectionCheckBox_Include.ValueChangedFcn = createCallbackFcn(app, @PreferredDirectionCheckBox_IncludeValueChanged, true);
            app.PreferredDirectionCheckBox_Include.Text = 'Preferred Direction';
            app.DirectionSelectivityIndexCheckBox_Include = uicheckbox(app.UIFigure);
            app.DirectionSelectivityIndexCheckBox_Include.ValueChangedFcn = createCallbackFcn(app, @DirectionSelectivityIndexCheckBox_IncludeValueChanged, true);
            app.DirectionSelectivityIndexCheckBox_Include.Text = 'Direction Selectivity Index';
            app.minDSIEditFieldLabel = uilabel(app.UIFigure);
            app.minDSIEditFieldLabel.Text = 'min DSI';
            app.minDSIEditField_Include = uieditfield(app.UIFigure, 'numeric');
            app.minDSIEditField_Include.Enable = 'off';
            app.minDSIEditField_Include.Value = 0.1;
            app.IncludeLabel = uilabel(app.UIFigure);
            app.IncludeLabel.Text = 'Include';
            app.PreferredDirectionCheckBox_Exclude = uicheckbox(app.UIFigure);
            app.PreferredDirectionCheckBox_Exclude.ValueChangedFcn = createCallbackFcn(app, @PreferredDirectionCheckBox_ExcludeValueChanged, true);
            app.PreferredDirectionCheckBox_Exclude.Text = 'Preferred Direction';
            app.DirectionSelectivityIndexCheckBox_Exclude = uicheckbox(app.UIFigure);
            app.DirectionSelectivityIndexCheckBox_Exclude.ValueChangedFcn = createCallbackFcn(app, @DirectionSelectivityIndexCheckBox_ExcludeValueChanged, true);
            app.DirectionSelectivityIndexCheckBox_Exclude.Text = 'Direction Selectivity Index';
            app.ExcludeLabel = uilabel(app.UIFigure);
            app.ExcludeLabel.Text = 'Exclude';
            app.CannotContainLabel = uilabel(app.UIFigure);
            app.CannotContainLabel.Text = 'Cannot Contain';
            app.List_Exclude = uitextarea(app.UIFigure);
            app.List_Exclude.Value = {'z'};
            app.TagsListBox_2Label = uilabel(app.UIFigure);
            app.TagsListBox_2Label.Text = 'Tags';
            app.TagsListBox_Exclude = uilistbox(app.UIFigure);
            app.TagsListBox_Exclude.Items = {'MTN', 'UP cell', 'DOWN Cell', 'Avoid For Tuning', 'Mislabeled', 'None', ''};
            app.TagsListBox_Exclude.Multiselect = 'on';
            app.TagsListBox_Exclude.Value = {'Mislabeled'};
            app.minangleEditField_2Label = uilabel(app.UIFigure);
            app.minangleEditField_2Label.Text = 'min angle';
            app.minangleEditField_Exclude = uieditfield(app.UIFigure, 'numeric');
            app.minangleEditField_Exclude.Enable = 'off';
            app.maxangleEditField_2Label = uilabel(app.UIFigure);
            app.maxangleEditField_2Label.Text = 'max angle';
            app.maxangleEditField_Exclude = uieditfield(app.UIFigure, 'numeric');
            app.maxangleEditField_Exclude.Enable = 'off';
            app.minDSIEditField_2Label = uilabel(app.UIFigure);
            app.minDSIEditField_2Label.Text = 'min DSI';
            app.minDSIEditField_Exclude = uieditfield(app.UIFigure, 'numeric');
            app.minDSIEditField_Exclude.Enable = 'off';
            app.minDSIEditField_Exclude.Value = 0.1;
            app.WillAnalyzeListBoxLabel = uilabel(app.UIFigure);
            app.WillAnalyzeListBoxLabel.Text = 'Will Analyze';
            app.WillAnalyzeListBox = uilistbox(app.UIFigure);
            app.WillAnalyzeListBox.Items = {};
            app.WillAnalyzeListBox.Multiselect = 'on';
            app.WillAnalyzeListBox.Value = {};
            app.TagSwitch_Include = uiswitch(app.UIFigure, 'slider');
            app.TagSwitch_Include.Items = {'&&', '||'};
            app.TagSwitch_Include.Value = '&&';
            app.TagSwitch_Exclude = uiswitch(app.UIFigure, 'slider');
            app.TagSwitch_Exclude.Items = {'&&', '||'};
            app.TagSwitch_Exclude.Value = '&&';
            app.TotalCellsLabel = uilabel(app.UIFigure);
            app.TotalCellsLabel.Text = 'Total Cells: 0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = batchAnalyses_OKR_Physiology

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end