classdef eyetrackingTracer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        ReverttolastsaveMenu          matlab.ui.container.Menu
        SavetoBatchMenu               matlab.ui.container.Menu
        SaveMenu                      matlab.ui.container.Menu
        SelectBatchListMenu           matlab.ui.container.Menu
        OptionsMenu                   matlab.ui.container.Menu
        SetDifferentCalibrationMenu   matlab.ui.container.Menu
        SaccadesMenu                  matlab.ui.container.Menu
        AddSaccadesMenu               matlab.ui.container.Menu
        VerticalMenu                  matlab.ui.container.Menu
        HorizontalMenu                matlab.ui.container.Menu
        RemoveSaccadesMenu            matlab.ui.container.Menu
        VerticalMenu_2                matlab.ui.container.Menu
        HorizontalMenu_2              matlab.ui.container.Menu
        FileTypeDropDownLabel         matlab.ui.control.Label
        FileTypeDropDown              matlab.ui.control.DropDown
        LoadAnimalButton              matlab.ui.control.Button
        SelectFileButton              matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        SelectionIndexEditFieldLabel  matlab.ui.control.Label
        SelectionIndexEditField       matlab.ui.control.NumericEditField
        SelectedLabel                 matlab.ui.control.Label
        ProtocolTagsListBoxLabel      matlab.ui.control.Label
        ProtocolTagsListBox           matlab.ui.control.ListBox
        ManuallyChangeaFileLabel      matlab.ui.control.Label
        AnalysisTypeDropDownLabel     matlab.ui.control.Label
        AnalysisTypeDropDown          matlab.ui.control.DropDown
        SubfieldEditFieldLabel        matlab.ui.control.Label
        SubfieldEditField             matlab.ui.control.EditField
        RunButton                     matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
        AnimalLabel                   matlab.ui.control.Label
        ActiveCalibrationLabel        matlab.ui.control.Label
    end

    
    properties (Access = public)
        animalPath % Path to folder containing this animal's recording data (..._record directory)
        SubfileLocations %Cell array containing the paths to stimulus, pupil, and DAQ files for each stimulus
        animalName %name of the animal that you've loaded data from
        behaviorListPath = 'C:\Users\mrsco\Box\DataAndAnalysis\labData\OKR\batch\';
        behaviorListFname = 'behaviorList_OKR.mat'
        behaviorList %nx2 cell array - column 1 = each animals name, column 2 = the eyetrackingParameters struct for each animal in the list
        eyetrackingParameters %the struct that holds analyzed data for this animal
        eyetrackingParameters_batch %the struct that holds analyzed data minus the heavy data (actual eye traces). This one lives in the batch list.
        eyetrackingParameters_lastVersion %old version of the eyetracking parameters structure that can be reverted to if needed. Overwritten on save
        eyetrackingParameters_batch_lastVersion%old version of the batch eyetracking parameters. updated on save.
        hasCalibration = 0; %says whether or not a calibration has been run for this animal
        activeCalibration = ''; %field name of the activate calibration
        lastAnalysisInfo = {}; %cell array that holds information about the most recently completed analysis. Filled during run callback
    end
    
    methods (Access = private)
        
        
        function findRecordingFiles(app, path, protocolNames)
            app.SubfileLocations = cell(numel(protocolNames), 4); %holds file names of required files
            isCalibration = {};
            for i = 1:numel(protocolNames)
                p_i = protocolNames{i};
                subPath_i = [path, '\', p_i, '\'];
                cd(subPath_i);
                
                %check if the current protocol is a pupil calibration
                if contains(lower(p_i), 'calibration')
                    isCalibration{end + 1} = true;
                else
                    isCalibration{end + 1} = false;
                end
                
                %find stimulus .json file
                JSONs = dir('*.json');
                if size(JSONs, 1) == 0 %no JSON file found
                    warning(['Could not locate a JSON stimulus file in this folder for ' p_i])
                    stimFile = NaN;
                elseif size(JSONs, 1) == 1 %1 JSON file found
                    stimFile = fullfile(JSONs.folder,JSONs.name);
                else    % More than one JSON file found
                    found = 0;
                    for j = 1:size(JSONs, 1)
                        if contains(lower(JSONs(j).name), '_complete') %look for the one that has suffix "complete" or "completed"
                            stimFile = fullfile(JSONs(j).folder, JSONs(j).name);
                            found = 1;
                            break
                        end
                    end
                    if ~found
                        disp(['Warning: Multiple JSON files located for protocol ' p_i ' and could not detect the correct one to use. Going with ' JSONs(1).name])
                        stimFile = fullfile(JSONs(1).folder(JSONs(j).name));
                    end
                end
                
                
                %determine whether there's a .mat file that corresponds to
                %the video information
                MATs = dir('*.mat');
                videoInfoFound = 0;
                if size(MATs, 1) > 0
                    for j = 1:size(MATs, 1)
                        if contains(lower(MATs(j).name), 'videoinfo')
                            videoInfoFound = 1;
                            vidFile = fullfile(MATs(j).folder, MATs(1).name); %call it the avi file
                        end
                    end
                end
                
                if ~videoInfoFound
                    %find .avi video file if there is no summary of the
                    %frame rate in a .mat file yet
                    AVIs = dir('*.avi');
                    if size(AVIs, 1) == 0
                        vidFile = NaN;
                    elseif size(AVIs, 1) == 1
                        vidFile = fullfile(AVIs(1).folder, AVIs(1).name);
                    elseif size(AVIs, 1) > 1
                        vidFile = fullfile(AVIs(1).folder, AVIs(1).name);
                        disp(['Multiple video files found for ' p_i, 'selecting the first one'])
                    end
                end


                %find pupil and DAQ files
                CSVs = dir('*.csv');
                
                pupilFound = 0;
                DAQFound = 0;
                
                for j = 1:size(CSVs, 1)
                    if contains(lower(CSVs(j).name), '_pupil.csv')
                        if pupilFound
                            disp(['Multiple pupil files found for ' p_i ' using the last one'])
                        end
                        pupilFile = fullfile(CSVs(j).folder, CSVs(j).name);
                        pupilFound = 1;
                    elseif contains(lower(CSVs(j).name), '_daq.csv')
                        if DAQFound
                            disp(['Multiple DAQ files found for ' p_i ' using the last one'])
                        end
                        DAQFile = fullfile(CSVs(j).folder, CSVs(j).name);
                        DAQFound = 1;
                    end
                end
                
                if ~pupilFound
                    pupilFile = NaN;
                    warning(['No pupil data found for ' p_i])
                end
                if ~DAQFound
                    DAQFile = NaN;
                    warning(['No DAQ data found for ' p_i])
                end
                
                %fill the cell structure that holds file locations
                app.SubfileLocations(i, :) = [{stimFile}, {pupilFile}, {DAQFile}, {vidFile}];
            end
            app.UITable.Data(:, 2:5) = app.SubfileLocations;
            app.UITable.Data(:, 6) = isCalibration;
        end
        
        function assignAnalysis(app)
            selection = app.SelectionIndexEditField.Value;
            isCalibration = app.UITable.Data{selection, 6};
            if isCalibration
                app.AnalysisTypeDropDown.Value = 'Calibration';
                
                %determine the date of the calibration:
                %load the stimulus json
                f = app.UITable.Data{selection, 2};
                fid = fopen(f);
                raw = fread(fid,inf);
                str = char(raw');
                fclose(fid);
                stimulusInfo = jsondecode(str);
                date = yyyymmdd(datetime(stimulusInfo.experimentStartTime, "InputFormat",'MM/dd/uuuu HH:mm:ss'));
                app.SubfieldEditField.Value = ['d20', num2str(date)];
                
                %for protocols that are not calibrations: add the suffix (if
                %there is one) as the subfield, otherwise assign to 'Standard'
            else
                protocolName = app.UITable.Data{selection, 1};
                suffixLoc = strfind(protocolName, '_');
                if suffixLoc > 0
                    protocol = protocolName(1:suffixLoc - 1);
                    suffix = protocolName(suffixLoc+1:end);
                else
                    protocol = protocolName;
                    suffix = 'Standard';
                end
                knownAnalysis = strcmp(app.AnalysisTypeDropDown.Items, protocol);
                
                for i = 1:numel(knownAnalysis)
                    if knownAnalysis(i)
                        app.AnalysisTypeDropDown.Value = ...
                            app.AnalysisTypeDropDown.Items(i);
                    end
                end
                
                app.SubfieldEditField.Value = suffix;
            end
        end
        
        function checkCalibrationStatus(app)
            %disallows the user from running any analyses before a
            %calibration has been done
            if app.hasCalibration || strcmp(app.AnalysisTypeDropDown.Value, 'Calibration')
                app.RunButton.Enable = 1;
                app.RunButton.Tooltip = 'Press to run current analysis';
                return
            end
            
            app.RunButton.Enable = 0;
            app.RunButton.Tooltip = 'You must run a pupil calibration before any other protocol';
            
        end
        
        function determineCalibrationToUse(app)
            %% try to determine which calibration to use for this stimulus
            if ~app.hasCalibration || strcmp(app.AnalysisTypeDropDown.Value, 'Calibration')
                app.ActiveCalibrationLabel.Text = 'Active Calibration: None';
                app.activeCalibration = '';
                return
            end
            
            %check if there is a selection yet
            if app.SelectionIndexEditField.Value == 0
                return
            end
            calibrations = app.eyetrackingParameters.Calibrations;
            
            %get the date of the selected stimulus
            selection = app.SelectionIndexEditField.Value;
            f = app.UITable.Data{selection, 2};
            fid = fopen(f);
            raw = fread(fid,inf);
            str = char(raw');
            fclose(fid);
            stimulusInfo = jsondecode(str);
            date = yyyymmdd(datetime(stimulusInfo.experimentStartTime, "InputFormat",'MM/dd/uuuu HH:mm:ss'));
            stimDate = str2num(['20', num2str(date)]);
            
            recordingDates = fieldnames(calibrations);
            allDatesAsNums = zeros(numel(recordingDates), 1);
            for i = 1:numel(recordingDates)
                date_i = recordingDates{i};
                allDatesAsNums(i) = str2num(date_i(2:end));
            end
            
            allDatesInOrder = sort(allDatesAsNums);
            
            beforeOrAfter = stimDate - allDatesInOrder; %negative number indicates the recording date happened after the stim date
            
            beforeStim = find(beforeOrAfter >= 0); %returns indices of the calibrations that happened before or on the same day as the stimulus
            
            if numel(beforeStim) >= 1
                
                nearestCalibration = beforeStim(end); %index of the nearest calibration that happened before or on the same day as the stimulus
                
                nearestCalibrationDate = allDatesInOrder(nearestCalibration);
            else
                %no calibrations happened before the stimulus -- choose the
                %first calibration then b/c/ it must have happened closest
                %to the stimulus
                disp(['No calibrations found that happened before this stimulus. Selecting the calibration that happened directly after'])
                nearestCalibrationDate = allDatesInOrder(1);
            end
            app.ActiveCalibrationLabel.Text = ['Active Calibration: ' num2str(nearestCalibrationDate)];
            app.activeCalibration = ['d' num2str(nearestCalibrationDate)];
        end
        
        function styleUITable(app)
            % adds highlight to selected UI row
            
            s = uistyle("BackgroundColor", [1, 1, 1]);
            addStyle(app.UITable, s, 'row', [1:2:size(app.UITable.Data, 1)]);
            s = uistyle("BackgroundColor", [0.9, 0.9, 0.9]);
            addStyle(app.UITable, s, 'row', [2:2:size(app.UITable.Data, 1)]);
            
            selection = app.SelectionIndexEditField.Value;
            if selection > 0 && selection <= size(app.UITable.Data, 1)
                s = uistyle('BackgroundColor', 'yellow');
                addStyle(app.UITable, s, 'row', app.SelectionIndexEditField.Value);
            end
        end
        
        function visualizeSaccades(app, eyeTrace, saccadeStruct, xlims)
            saccadeTimePoints = saccadeStruct.SaccadeStartFrameNumbers;
            
            figure
            title([app.animalName,': ', app.lastAnalysisInfo{1}])
            hold on
            plot(eyeTrace,'Color', 'k')
            s1 = scatter(saccadeTimePoints, eyeTrace(saccadeTimePoints), 'rd');
            legend('Eye Pos.', 'Saccades')
            ylabel('Degrees')
            xlabel('Frame Number')
            xlim(xlims)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %try to load the behavior list for data across animals
            try
                load([app.behaviorListPath, app.behaviorListFname]) %loads 'behaviorList' variable to the workspace
                app.behaviorList = behaviorList;
            catch ME
                disp('Unable to locate behavior list. Continuing to load without access to previous analyses')
            end
            
        end

        % Button pushed function: LoadAnimalButton
        function LoadAnimalButtonPushed(app, event)
            path=uigetdir;                    % Calls 'uigetdir' to obtain the directory location from the user
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue where the figure is sent to the background.
            app.UIFigure.Visible = 'on';
            
            %make sure the selected folder has the keyword "_record" at the
            %end
            slashes = strfind(path, '\'); %needs to change for a mac
            folderName = path(slashes(end)+1:end);
            
            recordSuffix = folderName(end-6:end);
            has_record = strcmp(recordSuffix, '_record');
            
            if ~has_record
                warning('Folder name incorrect. You must select a folder with sequence [AnimalName + "_record"]')
                disp('...terminating load, please select another folder and try again')
                return
            end
            a = dir(path);
            i = 1;
            while i < numel(a) %remove non directories
                if a(i).isdir == 0
                    a(i) = [];
                    continue %don't iterate a if you took something out
                end
                i = i + 1;
            end
            b={a(:).name}';                      % Gets the name of the files/folders of the contents and stores them appropriately in a cell array
            b(ismember(b,{'.','..'})) = [];      % Removes unnecessary '.' and '..' results from the display.
            
            app.UITable.Data=b;    % fill in protocol names
            
            app.findRecordingFiles(path, b);
            
            app.animalName = folderName(1:end-7);
            app.AnimalLabel.Text = app.animalName;
            
            
            %determine whether this animal has been analyzed before, if so
            %load data from the behavior list
            found = 0;
            for i = 1:size(app.behaviorList, 1)
                name_i = app.behaviorList{i, 1};
                if strcmp(name_i, app.animalName)
                    disp([app.animalName ' has been analyzed before, loading previous data'])
                    app.eyetrackingParameters_batch = app.behaviorList{i, 2}; %load the struct;
                    found = 1;
                    %look for calibration file
                    app.hasCalibration = isfield(app.eyetrackingParameters_batch, 'Calibrations');
                    if app.hasCalibration
                        disp('A calibration has been run already')
                    else
                        disp('You must run a calibration before analyzing other protocols')
                    end
                    app.checkCalibrationStatus();
                    
                    %load the actual structure (with all the data) from the
                    %cell's folder -- an error will get thrown if this file
                    %doesn't exist. You can add a work around if you want,
                    %but you must consider how to deal with the fact that
                    %the batch structure doesn't contain all the data...
                    %probably best to just reanalyze everything in this
                    %animal in that case.
                    load(app.eyetrackingParameters_batch.fileLocation); %load the previously saved file (loads 'eyetrackingParameters' to workspace)
                    app.eyetrackingParameters = eyetrackingParameters;
                    break
                end
            end
            
            if ~found
                %first check to see if there is an analysis file saved for
                %this animal in the path location (i.e. maybe it exists in
                %the animal's folder but just not in the batch list).
                possibleLocation = path(1:slashes(end));
                cd(possibleLocation)
                MATs = dir('*.mat');
                foundPrevious = 0;
                for j = 1:size(MATs, 1)
                    if strcmp(MATs(j).name, 'eyetrackingResults.mat') %if you find a previously saved file (that wasn't in the batch list):
                        disp('This animal was not found in the batch list, but a previous eye tracking result was located for it')
                        load(fullfile(MATs(j).folder, MATs(j).name))
                        app.eyetrackingParameters = eyetrackingParameters;
                        app.eyetrackingParameters_batch = app.eyetrackingParameters;
                        disp('Batch structure set to full data structure')
                        app.SavetoBatchMenu.Checked = 0; %turn off batch updates -- user can turn them back on manually if desired
                        disp('Turned off batch updates')
                        foundPrevious = 1;
                        break
                    end
                end
                
                
                if ~foundPrevious %if no previous analysis structure was found, create a new one
                    disp(['Creating a new entry for ' app.animalName])
                    app.eyetrackingParameters = struct();
                    app.eyetrackingParameters.AnimalID = app.animalName;
                    app.eyetrackingParameters.fileLocation = fullfile(path(1:slashes(end)), 'eyetrackingResults.mat');
                    app.eyetrackingParameters_batch = app.eyetrackingParameters; %create the batch version
                    app.hasCalibration = 0;
                    disp('You must run a calibration before analyzing other protocols')
                    app.checkCalibrationStatus();
                end
            end
            
            app.animalPath = path;
            app.eyetrackingParameters_lastVersion = app.eyetrackingParameters; %initialize older version for reversion purposes.
            app.eyetrackingParameters_batch_lastVersion = app.eyetrackingParameters_batch;
            app.SaveButton.Enable = 1;
        end

        % Value changed function: SelectionIndexEditField
        function SelectionIndexEditFieldValueChanged(app, event)
            value = app.SelectionIndexEditField.Value;
            if value > 0 && value <= size(app.UITable.Data, 1)
                selectedProtocol = app.UITable.Data(value, 1);
                app.SelectedLabel.Text = selectedProtocol{1};
                app.SelectedLabel.Tooltip = selectedProtocol{1};
                app.assignAnalysis();
            end
            
            app.checkCalibrationStatus();
            app.determineCalibrationToUse();
            
            app.styleUITable(); %stylize the UI table
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            app.SelectionIndexEditField.Value = indices(1);
            selectedProtocol = app.UITable.Data(indices(1), 1);
            app.SelectedLabel.Text = selectedProtocol{1};
            app.SelectedLabel.Tooltip = selectedProtocol{1};
            
            app.assignAnalysis(); %try to figure out which analysis to use and fill the drop down list
            app.checkCalibrationStatus(); %see if a calibration has been run yet - if not gray out the run button unless user appears to be running a calibration
            
            app.determineCalibrationToUse(); %determine which calibration to use for this protocol
            
            app.styleUITable(); %stylize the UI table
        end

        % Value changed function: AnalysisTypeDropDown
        function AnalysisTypeDropDownValueChanged(app, event)
            app.checkCalibrationStatus();
            app.determineCalibrationToUse();
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %run the analysis
            tic;
            analysisName = app.AnalysisTypeDropDown.Value;
            disp(['Beginning analysis for ' analysisName])
            
            eval(['[analysisResults, analysisResults_batch] = ' analysisName '_eyetracking(app);'])
            if strcmp(analysisName, 'Calibration') %runs only if a calibration is being established.
                app.eyetrackingParameters.Calibrations.(app.SubfieldEditField.Value) = analysisResults;
                app.eyetrackingParameters_batch.Calibrations.(app.SubfieldEditField.Value) = analysisResults;
                app.hasCalibration = 1;
                app.checkCalibrationStatus();
                app.lastAnalysisInfo = [{app.SubfieldEditField}, {analysisResults}];
                toc
                return
            else
                app.eyetrackingParameters.CompletedAnalyses.(analysisName).(app.SubfieldEditField.Value) = analysisResults;
                app.eyetrackingParameters_batch.CompletedAnalyses.(analysisName).(app.SubfieldEditField.Value) = analysisResults_batch;
                app.lastAnalysisInfo = [{analysisName}, {app.SubfieldEditField.Value}, {analysisResults}, {analysisResults_batch}];
            end
            
            try
                if isfield(app.eyetrackingParameters_lastVersion.CompletedAnalyses.(analysisName), app.SubfieldEditField.Value)
                    warndlg('Warning: An anlysis with the same name has been completed before. Revert before saving to avoid overwriting.')
                    warning('Warning: An anlysis with the same name has been completed before. Revert before saving to avoid overwriting.')
                end
            catch %the if statement in the above try block may not execute if, for example CompletedAnalyses is not yet a field. If so, just move on because clearly this analysis has not been run before
            end
            toc
        end

        % Callback function: SaveButton, SaveMenu
        function SaveButtonPushed(app, event)
            %save data for individual cell
            slashes = strfind(app.animalPath, '\');
            saveLocation = app.animalPath(1:slashes(end)); %save at the *same level* as the _record directory
            
            savePath = [saveLocation, 'eyetrackingResults.mat'];
            eyetrackingParameters = app.eyetrackingParameters;
            save(savePath, 'eyetrackingParameters');
            
            disp(['Saved Data for ' app.animalName])
            %save behaviorList data
            if app.SavetoBatchMenu.Checked
                %reload the behavior list first
                app.startupFcn();
                
                %next find the proper index to use for this animal
                found = 0;
                for i = 1:size(app.behaviorList, 1)
                    name_i = app.behaviorList{i, 1};
                    if strcmp(name_i, app.animalName)
                        app.behaviorList{i, 2} = app.eyetrackingParameters_batch;
                        found = 1;
                        break
                    end
                end
                if ~found
                    app.behaviorList{end + 1, 1} = app.animalName;
                    app.behaviorList{end, 2} = app.eyetrackingParameters_batch;
                end
                
                %save batch file
                savePath = [app.behaviorListPath, app.behaviorListFname];
                behaviorList = app.behaviorList;
                save(savePath, 'behaviorList');
                disp('Saved batch list')
            end
            
            app.eyetrackingParameters_lastVersion = app.eyetrackingParameters; %make a copy in case future reversion is wanted.
            app.eyetrackingParameters_batch_lastVersion = app.eyetrackingParameters_batch;
            
        end

        % Menu selected function: SavetoBatchMenu
        function SavetoBatchMenuSelected(app, event)
            app.SavetoBatchMenu.Checked = abs(1-app.SavetoBatchMenu.Checked);
            if app.SaveToBatchMenu.Checked
                disp('Now saving to batch list')
            else
                disp('ALERT: Data is not being saved to the batch list')
            end
        end

        % Menu selected function: ReverttolastsaveMenu
        function ReverttolastsaveMenuSelected(app, event)
            app.eyetrackingParameters = app.eyetrackingParameters_lastVersion;
            app.eyetrackingParameters_batch = app.eyetrackingParameters_batch_lastVersion;
            disp('Reverted to last saved version')
        end

        % Menu selected function: SetDifferentCalibrationMenu
        function SetDifferentCalibrationMenuSelected(app, event)
            %manually choose a different calibration
            if ~app.hasCalibration
                disp('You must run at least one calibration for this animal before setting one')
                return
            end
            
            availableCalibrations = fieldnames(app.eyetrackingParameters.Calibrations);
            indx = listdlg('ListString', availableCalibrations);
            
            selectedCalibration = availableCalibrations{indx};
            app.ActiveCalibrationLabel.Text = ['Active Calibration: ', selectedCalibration(2:end)];
            app.activeCalibration = selectedCalibration;
        end

        % Menu selected function: VerticalMenu
        function VerticalMenuSelected(app, event)
            %% Add vertical saccades
            
            %first check that last analysis was not a calibration
            if numel(app.lastAnalysisInfo) <= 2
                disp('Cannot add saccades to pupil calibration')
                return
            end
            
            %get analysis
            lastAnalysis = app.lastAnalysisInfo{3};
            
            frameNumbers = inputdlg({'Start Frame', 'Stop Frame'}, 'Enter Frame Number for New Vertical Saccade');
            
            try
                startFrame = round(str2num(frameNumbers{1}));
                endFrame = round(str2num(frameNumbers{2}));
            catch
                warning('Enter integers only - cannot add saccade')
                return
            end
            
            %check that end is greater than start, but that they're not too
            %far apart
            
            eyeTrace = lastAnalysis.verticalEyeTrace_full;
            
            if endFrame - startFrame < 0 || endFrame-startFrame > 5 || startFrame < 0 || endFrame > numel(eyeTrace)
                warning('Start and stop frames are incorrect, aborting function')
                return
            end
            
            currentSaccades = lastAnalysis.verticalSaccades.SaccadeStartFrameNumbers;
            if ismember(startFrame, currentSaccades)
                disp('Not adding saccade because it is a duplicate of previously listed one')
                return
            end
            
            saccadeAmplitude = eyeTrace(endFrame) - eyeTrace(startFrame);
            saccadeDirection = sign(saccadeAmplitude);
            
            
            lastAnalysis.verticalSaccades.SaccadeStartFrameNumbers(end + 1) = startFrame;
            lastAnalysis.verticalSaccades.SaccadeDurations_frames(end + 1) = endFrame - startFrame;
            lastAnalysis.verticalSaccades.SaccadeAmplitudes(end + 1) = saccadeAmplitude;
            lastAnalysis.verticalSaccades.SaccadeDirections(end + 1) = saccadeDirection;
            lastAnalysis.verticalSaccades.DetectionParameters.addedManually(end + 1) = startFrame;
            
            field1 = app.lastAnalysisInfo{1};
            field2 = app.lastAnalysisInfo{2};
            
            app.eyetrackingParameters.CompletedAnalyses.(field1).(field2).verticalSaccades = lastAnalysis.verticalSaccades;
            app.eyetrackingParameters_batch.CompletedAnalyses.(field1).(field2).verticalSaccades = lastAnalysis.verticalSaccades;
            
            app.lastAnalysisInfo = [{field1}, {field2}, {app.eyetrackingParameters.CompletedAnalyses.(field1).(field2)}...
                app.eyetrackingParameters_batch.CompletedAnalyses.(field1).(field2)];
            
            figXLims = [startFrame - 50, startFrame + 50];
            
            app.visualizeSaccades(eyeTrace, lastAnalysis.verticalSaccades, figXLims);
            if saccadeDirection == 1
                disp(['Added positive vertical saccade at frame ' num2str(startFrame) ' to ' num2str(endFrame)])
            else
                disp(['Added negative vertical saccade at frame ' num2str(startFrame) ' to ' num2str(endFrame)])
            end
        end

        % Menu selected function: HorizontalMenu
        function HorizontalMenuSelected(app, event)
            %%Add Horizontal Saccades
            
            %first check that last analysis was not a calibration
            if numel(app.lastAnalysisInfo) <= 2
                disp('Cannot add saccades to pupil calibration')
                return
            end
            
            %get analysis
            lastAnalysis = app.lastAnalysisInfo{3};
            frameNumbers = inputdlg({'Start Frame', 'Stop Frame'}, 'Enter Frame Number for New Horizontal Saccade');
            
            try
                startFrame = round(str2num(frameNumbers{1}));
                endFrame = round(str2num(frameNumbers{2}));
            catch
                warning('Enter integers only - cannot add saccade')
                return
            end
            
            %check that end is greater than start, but that they're not too
            %far apart
            eyeTrace = lastAnalysis.horizontalEyeTrace_full;
            if endFrame - startFrame < 0 || endFrame-startFrame > 5 || startFrame < 0 || endFrame > numel(eyeTrace)
                warning('Start and stop frames are too far apart, aborting function')
                return
            end
            
            currentSaccades = lastAnalysis.horizontalSaccades.SaccadeStartFrameNumbers;
            if ismember(startFrame, currentSaccades)
                disp('Not adding saccade because it is a duplicate of previously listed one')
                return
            end
            saccadeAmplitude = eyeTrace(endFrame) - eyeTrace(startFrame);
            saccadeDirection = sign(saccadeAmplitude);
            
            lastAnalysis.horizontalSaccades.SaccadeStartFrameNumbers(end + 1) = startFrame;
            lastAnalysis.horizontalSaccades.SaccadeDurations_frames(end + 1) = endFrame - startFrame;
            lastAnalysis.horizontalSaccades.SaccadeAmplitudes(end + 1) = saccadeAmplitude;
            lastAnalysis.horizontalSaccades.SaccadeDirections(end + 1) = saccadeDirection;
            lastAnalysis.horizontalSaccades.DetectionParameters.addedManually(end + 1) = startFrame;
            
            field1 = app.lastAnalysisInfo{1};
            field2 = app.lastAnalysisInfo{2};
            
            app.eyetrackingParameters.CompletedAnalyses.(field1).(field2).horizontalSaccades = lastAnalysis.horizontalSaccades;
            app.eyetrackingParameters_batch.CompletedAnalyses.(field1).(field2).horizontalSaccades = lastAnalysis.horizontalSaccades;
            
            app.lastAnalysisInfo = [{field1}, {field2}, {app.eyetrackingParameters.CompletedAnalyses.(field1).(field2)}...
                app.eyetrackingParameters_batch.CompletedAnalyses.(field1).(field2)];
            
            figXLims = [startFrame - 50, startFrame + 50];
            app.visualizeSaccades(eyeTrace, lastAnalysis.horizontalSaccades, figXLims);
            if saccadeDirection == 1
                 disp(['Added positive horizontal saccade at frame ' num2str(startFrame) ' to ' num2str(endFrame)])
            else
                disp(['Added negative horizontal saccade at frame ' num2str(startFrame) ' to ' num2str(endFrame)])
            end
        end

        % Menu selected function: VerticalMenu_2
        function VerticalMenu_2Selected(app, event)
            %% remove vertical saccades
            
            %first check that last analysis was not a calibration
            if numel(app.lastAnalysisInfo) <= 2
                disp('Cannot remove saccades from pupil calibration')
                return
            end
            
            %get analysis
            lastAnalysis = app.lastAnalysisInfo{3};
            availableSaccades = lastAnalysis.verticalSaccades.SaccadeStartFrameNumbers;
            
            %ask which saccade to remove
            listStr = {};
            for i = 1:numel(availableSaccades)
                listStr(end + 1) = {num2str(availableSaccades(i))};
            end
            saccadeIndex = listdlg('ListString', listStr, 'Name', 'Select Saccade Index to Remove', 'SelectionMode', 'Single');
            if isempty(saccadeIndex)
                disp('Removal Canceled, aporting function')
                return
            end
            
            removalSaccade = str2double(listStr{saccadeIndex});
            removalIndex = find(availableSaccades == removalSaccade);
            
            %remove the saccade and its associated values
            lastAnalysis.verticalSaccades.SaccadeStartFrameNumbers(removalIndex) = [];
            lastAnalysis.verticalSaccades.SaccadeDurations_frames(removalIndex) = [];
            lastAnalysis.verticalSaccades.SaccadeAmplitudes(removalIndex) = [];
            lastAnalysis.verticalSaccades.SaccadeDirections(removalIndex) = [];
            lastAnalysis.verticalSaccades.DetectionParameters.removedManually(end + 1) = removalSaccade;
            
            field1 = app.lastAnalysisInfo{1};
            field2 = app.lastAnalysisInfo{2};
            
            app.eyetrackingParameters.CompletedAnalyses.(field1).(field2).verticalSaccades = lastAnalysis.verticalSaccades;
            app.eyetrackingParameters_batch.CompletedAnalyses.(field1).(field2).verticalSaccades = lastAnalysis.verticalSaccades;
            
            app.lastAnalysisInfo = [{field1}, {field2}, {app.eyetrackingParameters.CompletedAnalyses.(field1).(field2)}...
                app.eyetrackingParameters_batch.CompletedAnalyses.(field1).(field2)];
            
            figXLims = [removalSaccade - 50, removalSaccade + 50];
            app.visualizeSaccades(lastAnalysis.verticalEyeTrace_full, lastAnalysis.verticalSaccades, figXLims);
            disp(['Removed vertical saccade at frame ' num2str(removalSaccade)])
        end

        % Menu selected function: HorizontalMenu_2
        function HorizontalMenu_2Selected(app, event)
            %% Remove Horizontal Saccades
            
            %first check that last analysis was not a calibration
            if numel(app.lastAnalysisInfo) <= 2
                disp('Cannot remove saccades from pupil calibration')
                return
            end
            
            %get analysis
            lastAnalysis = app.lastAnalysisInfo{3};
            availableSaccades = lastAnalysis.horizontalSaccades.SaccadeStartFrameNumbers;
            
            %ask which saccade to remove
            listStr = {};
            for i = 1:numel(availableSaccades)
                listStr(end + 1) = {num2str(availableSaccades(i))};
            end
            saccadeIndex = listdlg('ListString', listStr, 'Name', 'Select Saccade Index to Remove', 'SelectionMode', 'Single');
            
            if isempty(saccadeIndex)
                disp('Removal Canceled, aporting function')
                return
            end
            
            removalSaccade = str2double(listStr{saccadeIndex});
            removalIndex = find(availableSaccades == removalSaccade);
            
            %remove the saccade and its associated values
            lastAnalysis.horizontalSaccades.SaccadeStartFrameNumbers(removalIndex) = [];
            lastAnalysis.horizontalSaccades.SaccadeDurations_frames(removalIndex) = [];
            lastAnalysis.horizontalSaccades.SaccadeAmplitudes(removalIndex) = [];
            lastAnalysis.horizontalSaccades.SaccadeDirections(removalIndex) = [];
            lastAnalysis.horizontalSaccades.DetectionParameters.removedManually(end + 1) = removalSaccade;
            
            field1 = app.lastAnalysisInfo{1};
            field2 = app.lastAnalysisInfo{2};
            
            app.eyetrackingParameters.CompletedAnalyses.(field1).(field2).horizontalSaccades = lastAnalysis.horizontalSaccades;
            app.eyetrackingParameters_batch.CompletedAnalyses.(field1).(field2).horizontalSaccades = lastAnalysis.horizontalSaccades;
            
            app.lastAnalysisInfo = [{field1}, {field2}, {app.eyetrackingParameters.CompletedAnalyses.(field1).(field2)}...
                app.eyetrackingParameters_batch.CompletedAnalyses.(field1).(field2)];
            
             figXLims = [removalSaccade - 50, removalSaccade + 50];
            app.visualizeSaccades(lastAnalysis.horizontalEyeTrace_full, lastAnalysis.horizontalSaccades, figXLims);
            disp(['Removed horizontal saccade at frame ' num2str(removalSaccade)])
        end

        % Menu selected function: SelectBatchListMenu
        function SelectBatchListMenuSelected(app, event)
            [f, p] = uigetfile();
            load(fullfile(p, f)) %loads 'behaviorList' variable to the workspace
            try
                 app.behaviorList = behaviorList;
                 app.behaviorListPath = p;
                 app.behaviorListFname = f;
                 disp(['Loaded new behavioralist called ', fullfile(p, f)])
            catch
                disp('Could not load file. You may only select a .mat file that contains a variable called behaviorList')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ReverttolastsaveMenu = uimenu(app.FileMenu);
            app.ReverttolastsaveMenu.MenuSelectedFcn = createCallbackFcn(app, @ReverttolastsaveMenuSelected, true);
            app.ReverttolastsaveMenu.Tooltip = {'Revert the last saved analysis structure. This is a perminant action. Analysis may be lost.'};
            app.ReverttolastsaveMenu.Text = 'Revert to last save';
            app.SavetoBatchMenu = uimenu(app.FileMenu);
            app.SavetoBatchMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoBatchMenuSelected, true);
            app.SavetoBatchMenu.Checked = 'on';
            app.SavetoBatchMenu.Text = 'Save to Batch';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveMenu.Text = 'Save';
            app.SelectBatchListMenu = uimenu(app.FileMenu);
            app.SelectBatchListMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectBatchListMenuSelected, true);
            app.SelectBatchListMenu.Tooltip = {'Select the batch list that you want to use. Auto loads to OKR project.'};
            app.SelectBatchListMenu.Text = 'Select Batch List';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.SetDifferentCalibrationMenu = uimenu(app.OptionsMenu);
            app.SetDifferentCalibrationMenu.MenuSelectedFcn = createCallbackFcn(app, @SetDifferentCalibrationMenuSelected, true);
            app.SetDifferentCalibrationMenu.Text = 'Set Different Calibration';
            app.SaccadesMenu = uimenu(app.UIFigure);
            app.SaccadesMenu.Text = 'Saccades';
            app.AddSaccadesMenu = uimenu(app.SaccadesMenu);
            app.AddSaccadesMenu.Text = 'Add Saccades';
            app.VerticalMenu = uimenu(app.AddSaccadesMenu);
            app.VerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @VerticalMenuSelected, true);
            app.VerticalMenu.Text = 'Vertical';
            app.HorizontalMenu = uimenu(app.AddSaccadesMenu);
            app.HorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @HorizontalMenuSelected, true);
            app.HorizontalMenu.Text = 'Horizontal';
            app.RemoveSaccadesMenu = uimenu(app.SaccadesMenu);
            app.RemoveSaccadesMenu.Text = 'Remove Saccades';
            app.VerticalMenu_2 = uimenu(app.RemoveSaccadesMenu);
            app.VerticalMenu_2.MenuSelectedFcn = createCallbackFcn(app, @VerticalMenu_2Selected, true);
            app.VerticalMenu_2.Text = 'Vertical';
            app.HorizontalMenu_2 = uimenu(app.RemoveSaccadesMenu);
            app.HorizontalMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HorizontalMenu_2Selected, true);
            app.HorizontalMenu_2.Text = 'Horizontal';
            app.FileTypeDropDownLabel = uilabel(app.UIFigure);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDropDown = uidropdown(app.UIFigure);
            app.FileTypeDropDown.Items = {'Stimulus (JSON)', 'Pupil (CSV)', 'DAQ (CSV)'};
            app.FileTypeDropDown.Value = 'Stimulus (JSON)';
            app.LoadAnimalButton = uibutton(app.UIFigure, 'push');
            app.LoadAnimalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnimalButtonPushed, true);
            app.LoadAnimalButton.Text = 'Load Animal';
            app.SelectFileButton = uibutton(app.UIFigure, 'push');
            app.SelectFileButton.Text = 'Select File';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Protocol'; 'Stimulus'; 'Pupil'; 'DAQ'; 'AVI'; 'Calibration'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.SelectionIndexEditFieldLabel = uilabel(app.UIFigure);
            app.SelectionIndexEditFieldLabel.Text = 'Selection Index';
            app.SelectionIndexEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectionIndexEditField.ValueChangedFcn = createCallbackFcn(app, @SelectionIndexEditFieldValueChanged, true);
            app.SelectedLabel = uilabel(app.UIFigure);
            app.SelectedLabel.Text = 'No protocol selected';
            app.ProtocolTagsListBoxLabel = uilabel(app.UIFigure);
            app.ProtocolTagsListBoxLabel.Text = 'Protocol Tags';
            app.ProtocolTagsListBox = uilistbox(app.UIFigure);
            app.ProtocolTagsListBox.Items = {'Prelim Data', 'Second Recording Day'};
            app.ProtocolTagsListBox.Multiselect = 'on';
            app.ProtocolTagsListBox.Value = {};
            app.ManuallyChangeaFileLabel = uilabel(app.UIFigure);
            app.ManuallyChangeaFileLabel.Text = 'Manually Change a File';
            app.AnalysisTypeDropDownLabel = uilabel(app.UIFigure);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.UIFigure);
            app.AnalysisTypeDropDown.Items = {'Calibration', 'StaticGrating', 'MovingGratingDirection', 'OscillatingGrating'};
            app.AnalysisTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysisTypeDropDownValueChanged, true);
            app.AnalysisTypeDropDown.Value = 'Calibration';
            app.SubfieldEditFieldLabel = uilabel(app.UIFigure);
            app.SubfieldEditFieldLabel.Text = 'Subfield';
            app.SubfieldEditField = uieditfield(app.UIFigure, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.AnimalLabel = uilabel(app.UIFigure);
            app.AnimalLabel.Text = 'No Animal Loaded';
            app.ActiveCalibrationLabel = uilabel(app.UIFigure);
            app.ActiveCalibrationLabel.Text = 'Active Calibration: None';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eyetrackingTracer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end