classdef dataViewerApp_OKR_Physiology < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dataViewerUI                matlab.ui.Figure
        AnalysisOptionsMenu         matlab.ui.container.Menu
        SpikeDetectionMenu          matlab.ui.container.Menu
        spikeDetector_SKMenu        matlab.ui.container.Menu
        simpleSpikeDetector_SHMenu  matlab.ui.container.Menu
        UITable                     matlab.ui.control.Table
        CellIDDataViewerLabel       matlab.ui.control.Label
        RunAnalysisButton           matlab.ui.control.Button
        RecordingTypeDropDownLabel  matlab.ui.control.Label
        RecordingTypeDropDown       matlab.ui.control.DropDown
        ViewDetailsButton           matlab.ui.control.Button
        EpochEditFieldLabel         matlab.ui.control.Label
        EpochEditField              matlab.ui.control.EditField
        PlotRawDataButton           matlab.ui.control.Button
        WillAnalyzeEditFieldLabel   matlab.ui.control.Label
        WillAnalyzeEditField        matlab.ui.control.EditField
        ViewSingleEpochsLabel       matlab.ui.control.Label
        SetAnalysisParametersLabel  matlab.ui.control.Label
        AnalysisTypeDropDownLabel   matlab.ui.control.Label
        AnalysisTypeDropDown        matlab.ui.control.DropDown
        SaveButton                  matlab.ui.control.Button
        savemessageLabel            matlab.ui.control.Label
        RevertButton                matlab.ui.control.Button
        SuffixEditFieldLabel        matlab.ui.control.Label
        SuffixEditField             matlab.ui.control.EditField
        SaveFigureButton            matlab.ui.control.Button
        FigureSavedLabel            matlab.ui.control.Label
        AnalysisTagsListBoxLabel    matlab.ui.control.Label
        AnalysisTagsListBox         matlab.ui.control.ListBox
        CellTypeButtonGroup         matlab.ui.container.ButtonGroup
        ONButton                    matlab.ui.control.RadioButton
        ONOFFButton                 matlab.ui.control.RadioButton
        OFFButton                   matlab.ui.control.RadioButton
        UpdateCellListSwitchLabel   matlab.ui.control.Label
        UpdateCellListSwitch        matlab.ui.control.Switch
        ContextMenu                 matlab.ui.container.ContextMenu
        PlotOptionsMenu             matlab.ui.container.Menu
        TraceOnlyMenu               matlab.ui.container.Menu
        ShowSpikeDetectionMenu      matlab.ui.container.Menu
    end

    
    properties (Access = public)
        cellID %cellID input argument (string)
        originalData %data input argument
        CellParameters %.mat structure that is either loaded from path or created and saved to proper location
    end
    
    properties (Access = private)
        PresstoproceedButtonState
        analysisFigure % figure handle for most recent figure produced by analysis
        lastAnalysisType %name of the last analysisType done (used in saving figure)
        lastRecordingTypeAndSuffix %name of last recording type and suffix (used in saving figure)
        cellList % list of all cells that have been analyzed before... this will be updated by the app as the current cell's data is saved.
        cellListPath %path of cell list
        analysesPerformed %cell array that lists analyses done since last save and/or revert. Each list item is a cell array in itself where the first element is str of the recording type and the analysisType and the second element is the recordingTypeAndSuffix
    end
    
    
    methods (Access = private) %helper functions
        
        function fillCellParameters(app, processedDataStruct)
            %Here we add the analysis that was just done to the
            %CellParameters structure, which contains all analyses done for
            %this cell so far.
            
            %Two field names give the analysis specificity: the first is
            %the "analysis type" (e.g. flash, moving grating) and the
            %second is the "recording type" (e.g. extracellular). These names
            %are the first and second branches of processedDataStruct,
            %respectively, and we'd like to preserve them. However, we can
            %save multiple repetitions of an analysis seperately if a
            %suffix has been speficied by the user. The suffix will be
            %added on to the "recording type" field name (e.g.
            %extracellular_2).
            justAnalyzed = processedDataStruct; %simple rename of data struct to add
            %get analysis and recording types, along with the suffix if it
            %exists
            fieldToAdd1 = fieldnames(justAnalyzed);
            analysisType = fieldToAdd1{1};
            fieldToAdd2 = fieldnames(justAnalyzed.(analysisType));
            recordingType = fieldToAdd2{1};
            recordingTypeAndSuffix = strcat(recordingType, app.SuffixEditField.Value);
            
            %Ask if the analysis type + recording type has been analyzed
            %before
            if isfield(app.CellParameters, 'Analyses_Completed')...
                    && isfield(app.CellParameters.Analyses_Completed, analysisType)...
                    && isfield(app.CellParameters.Analyses_Completed.(analysisType), recordingTypeAndSuffix)
                %Notify user if their duplicating a branch of the
                %structure. Permanent overwriting will happen if they save,
                %but they still have the option of "reverting" after
                %pressing okay

                waitfor(msgbox(['This analysis has been performed. Previously saved data will' ...
                    ' be permamently overwritten unless the "Revert" button is pressed.'], 'Overwrite Warning'))
            end
            
            %fill cell parameters
            app.CellParameters.Analyses_Completed.(analysisType).(recordingTypeAndSuffix).Analysis_Results =...
                justAnalyzed.(analysisType).(recordingType);
            app.CellParameters.Analyses_Completed.(analysisType).(recordingTypeAndSuffix).meta  =...
                justAnalyzed.(analysisType).(recordingType).meta;
            app.CellParameters.Cell_Type.Physiology = justAnalyzed.cellType;


            %give user ability to save the data structure
            app.SaveButton.Visible = true;
            app.SaveButton.Enable = true;
            
            %temporarily save some data about the last analysis done
            %(SaveFigureButtonPushed)
            app.lastAnalysisType = analysisType;
            app.lastRecordingTypeAndSuffix = recordingTypeAndSuffix;
        
        end


        function updateCellList(app)
            
            %cellList is an Nx2 cell array. Column 1 contains the names of
            %all the cells that have been analyzed. Column 2 contains a
            %structure for that cell with information about it
            
            %load seperately on each save so you get the most up to date
            %version. If you load cell list in startup then multiple cells
            %being analayzed at once will have old copies of the file
            
            load(strcat(app.cellListPath, 'cellList_OKR.mat')); %loads variable with name "cellList" to the workspace (Nx2 cell array, column 1 = strings of cell names, column 2 = structs for each cell saying if it has been analyzed).
            app.cellList = cellList;
            clear cellList
            
            %look if cell has been analyzed before. If so, simply update
            %the entry. If not, append a new entry to cellList.
            cellIndex = 0;
            for i = 1:size(app.cellList, 1)
                if strcmp(app.cellID, app.cellList{i, 1})
                    cellIndex = i;
                    break
                end
            end
            
            %add all the analyses listed in app.analysesPerformed to the
            %cellList       
                if cellIndex > 0 %cell was found in cellList
                    cellRecordStruct = app.cellList{cellIndex, 2};
                else %cell wasn't already in cellList
                    cellIndex = size(app.cellList, 1)+1;
                    cellRecordStruct = struct();
                end
                
                cellRecordStruct = app.CellParameters;

                cellRecordStruct.updateHTML = 1; %tag used by python to know who to update
               
                app.cellList{cellIndex, 1} = app.cellID;
                app.cellList{cellIndex, 2} = cellRecordStruct;

                cellList = app.cellList;
                save(strcat(app.cellListPath, 'cellList_OKR.mat'), "cellList")
                
                clear cellList
                
                %clear app.analysesPerformed, it will be refilled if subsequent
                %analyses are run
                app.analysesPerformed = {};
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, cell_id, cellListPath)
            %Inputs are: data = data structure formed by master script
            %cell_id = string corresponding to cell's name as it appears as
            %a field in the data structure (e.g. 'SH13Lc1')
            app.CellIDDataViewerLabel.Text = [cell_id ' - Data Viewer'];
            expGroup_id = data.(cell_id).epochLog(1,:);
            startStopIndex = data.(cell_id).epochLog(2, :);
            d = cell(numel(expGroup_id), 9);
            
            %build cell array d w/ data that you'll want to present in the
            %gui (UITable)
            for i = 1:numel(expGroup_id)
                    d{i, 1} = false;
                    d{i, 2} = expGroup_id{i};
                    startIndex = startStopIndex{i}(1);%start index
                    stopIndex = startStopIndex{i}(2);%stop index
                    numEpochs = stopIndex-startIndex + 1;
                    try
                        onlineAnalysisType = data.(cell_id).epochs(startIndex).meta.onlineAnalysis;
                    catch
                        onlineAnalysisType = 'unknown';
                    end
                    d{i, 3} = numEpochs;
                    d{i, 4} = startIndex;
                    d{i, 5} = stopIndex;
                    d{i, 6} = onlineAnalysisType;
                    d{i, 7} = data.(cell_id).epochs(startIndex).meta.preTime;                    
                    d{i, 8} = data.(cell_id).epochs(startIndex).meta.stimTime;
            end
                
            app.UITable.Data = d; %fill UITable with data for this cell
            app.UITable.ColumnName = {'Analyze', 'Protocol', '# Epochs', 'Start Index',...
                'Stop Index', 'Online Analysis', 'Pre Time', 'Stim Time'};
            app.UITable.ColumnEditable = true;
            
            app.cellID = cell_id;
            app.originalData = data;
            
            app.EpochEditField.Value = 'View an Epoch #';
            
            % Check for existence of CellParameters structure in file
            % location. If it doesn't exist initialize it, otherwise just
            % load it.
            cp_path = strcat(data.(cell_id).fileLocation, cell_id, '\', cell_id, '_Analysis.mat');
            if exist(cp_path) == 2
                load(cp_path); %loads to workspace as variable called "CellParameters"
                app.CellParameters = CellParameters;
                app.CellParameters.fileLocation = strcat(data.(cell_id).fileLocation, cell_id, '\');
                app.CellParameters.coordinates = data.(cell_id).coordinates;
            else
                cp = struct();
                cp.cellID = cell_id;
                cp.fileLocation = strcat(data.(cell_id).fileLocation, cell_id, '\');
                cp.coordinates = data.(cell_id).coordinates;
                
                app.CellParameters = cp;
            end
            
            % Load the list of all cells and save it as a property.
            app.cellListPath = cellListPath;
            app.analysesPerformed = {};
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)

            
            %!!!!-----------------------------------------------------------------!!!
            %HERE WE CALL THE ANALYZER_OKR_PHYSIOLOGY FUNCTION WHEN THE RUN
            %ANALYSIS BUTTON IS PUSHED
            
            %analyzer_OKR_physiology exists outside of this app and is
            %where the bulk of the actual analysis is conducted from. It
            %can be found in /Users/mrsco/Box/DataAndAnalysis
            %/labData/OKR/Physiology/matlabCode
            data = app.originalData;
            recordingType = app.RecordingTypeDropDown.Value;
            analysisType = app.AnalysisTypeDropDown.Value;
            epochsSelected = str2num(app.WillAnalyzeEditField.Value);
            cellType = app.CellTypeButtonGroup.SelectedObject.Text;
            
            [processedDataStruct, app.analysisFigure] = analyzer_OKR_Physiology(data, recordingType, analysisType,...
                epochsSelected, app.cellID, cellType);
            %processedDataStruct is a structure containing the single
            %analysis that you just performed. analysisFigure is the figure
            %handle to the figure that the analysis produced.
            %!!!-------------------------------------------------------------------!!!
            
            %add the new analaysis to CellParameters, which is a larger
            %structure containing all the analyses that have been done for
            %this cell.
            app.fillCellParameters(processedDataStruct);
            
            %update list of latest recording types run
            app.analysesPerformed{end + 1, 1} = {app.lastAnalysisType, app.lastRecordingTypeAndSuffix};
                        
            %update buttons and show saved statuses
            app.SaveFigureButton.Visible = true;
            app.SaveFigureButton.FontColor = [1, 0, 0];
            app.FigureSavedLabel.Visible = false;
            app.SaveButton.FontColor = [1, 0, 0];
            app.RevertButton.Visible = true;
            

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            currentSelectedIndices = event.Indices;
            rowToSee = currentSelectedIndices(1);
            startIndexOfRowToSee = app.UITable.Data(rowToSee, 4);
            app.EpochEditField.Value = num2str(startIndexOfRowToSee{1});
        end

        % Button pushed function: ViewDetailsButton
        function ViewDetailsButtonPushed(app, event)
            openvar(['data.' app.cellID '.epochs(' app.EpochEditField.Value ').meta'])
        end

        % Button pushed function: PlotRawDataButton
        function PlotRawDataButtonPushed(app, event)
            trace = app.originalData.(app.cellID).epochs(str2double(app.EpochEditField.Value)).epoch(:)';
            meta = app.originalData.(app.cellID).epochs(str2double(app.EpochEditField.Value)).meta;
            totalTime = meta.preTime + meta.stimTime + meta.tailTime;
            time = linspace(0, totalTime, numel(trace));
            
            %Executes only when you want to see the raw trace alone
            figure()
            hold on
            plot(time, trace)
            title([app.cellID ' Epoch# ' app.EpochEditField.Value ' - raw trace'])
            plot([meta.preTime meta.preTime+meta.stimTime],...
                [mean(trace) + 12*std(trace), mean(trace) + 12*std(trace)],...
                'Color', 'k', 'LineWidth', 2)
            xlabel('time')
            
            if app.TraceOnlyMenu.Checked
                hold off
                return %stop here if you just want to see the trace
            end
            
            %Executes only when you want to see the spike detection on the
            %raw trace
            if app.spikeDetector_SKMenu.Checked
                spikeData = SpikeDetector_SK(trace);
            elseif app.simpleSpikeDetector_SHMenu.Checked
                spikeData = simpleSpikeDetector_SH(trace);
            end
            
            spikeTimes = spikeData.sp./10; %convert to ms by dividing by 1000
            yPos = 8*std(trace) .* ones(1, numel(spikeTimes));
            scatter(spikeTimes, yPos, 'dk')
            hold off
            
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            
            epochsToAnalyze = [];
            
            %Make a guess as to what type of analysis will be run
            analysisGuess = app.UITable.Data{1, 2};
            if ismember(analysisGuess, app.AnalysisTypeDropDown.Items)
                app.AnalysisTypeDropDown.Value = analysisGuess;
            end
            
            if indices(2) == 1 %first column edits only
                for i = 1:size(app.UITable.Data, 1)
                    if app.UITable.Data{i, 1} == true
                        analysisGuess = app.UITable.Data{i, 2};
                        %Make a guess as to what type of analysis will be run
                        if ismember(analysisGuess, app.AnalysisTypeDropDown.Items)
                            app.AnalysisTypeDropDown.Value = analysisGuess;
                        end
                        
                        %update epochs to analyze field
                        start = app.UITable.Data{i,4};
                        stop = app.UITable.Data{i, 5};
                        epochsToAnalyze = [epochsToAnalyze start:stop];
                    end
                end
                if ~isempty(epochsToAnalyze)
                    app.WillAnalyzeEditField.Value = num2str(epochsToAnalyze);
                else
                    app.WillAnalyzeEditField.Value = ' ';
                end
                
            end
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            %resets cell parameters to the last saved version... you can
            %never revert further back than the last time you saved.
            cp_path = strcat(app.originalData.(app.cellID).fileLocation, app.cellID, '\', app.cellID, '_Analysis.mat');
            if exist(cp_path) == 2
                load(cp_path);
                app.CellParameters = CellParameters;
            else
                cp = struct();
                cp.cellID = app.cellID;
                cp.fileLocation = strcat(app.originalData.(app.cellID).fileLocation, app.cellID, '\');
                cp.coordinates = app.originalData.(app.cellID).coordinates;
                app.CellParameters = cp;
            end
            app.SaveButton.Visible = false; %show unsaved
            app.SaveFigureButton.Visible = false;
            app.RevertButton.Visible = false;
            
            %reset analysesPerformed, which helps update cellList with the
            %latest analyses run since last save.
            app.analysesPerformed = {};
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %save data when user requests
            saveLocation = app.CellParameters.fileLocation;
            
            %Add analysis Tags and Physiology Cell Type
            app.CellParameters.Tags = app.AnalysisTagsListBox.Value; %CHANGE THIS ONCE YOU START DOING RETROGRADE INJECTIONS
            %app.CellParameters.CellType.Physiology = app.CellTypeButtonGroup.Value;
            
            %check if the subfolder exists for this cell, if not create it.
            if exist(saveLocation)~=7
                mkdir(saveLocation);
            end
            
            fname = strcat(saveLocation, app.cellID, '_Analysis.mat');
            CellParameters = app.CellParameters;
            save(fname, 'CellParameters') %save a .mat file containing structure "CellParameters"
            
            app.updateCellList(); %update the cellList to note a new save for this cell.
           
            app.SaveButton.FontColor = [.47,0.67,0.19]; %button changes to green
            app.savemessageLabel.Text = strcat('Success! Saved analyzed data to ', fname);
            app.savemessageLabel.Visible = true;
            
            app.RevertButton.Visible = false; %take away revert button because reverting will just bring back the copy that was last saved... i.e. this one
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            saveLocation = app.CellParameters.fileLocation;
            
            %check if the subfolder exists for this cell, if not create it.
            if exist(saveLocation)~=7
                mkdir(saveLocation);
            end
            
            %save the figure via to the correct folder by referencing its
            %handle (which has been temporarily saved as a property)
            fname = strcat(saveLocation, app.lastAnalysisType, '_',app.lastRecordingTypeAndSuffix, '.svg');
            saveas(app.analysisFigure, fname);
            
            if strcmp(app.UpdateCellListSwitch.Value, 'On')
                app.updateCellList(); %update the cellList to note a new save for this cell.
            else
                disp('Cell list batch updates are turned off...')
            end

            app.SaveFigureButton.FontColor = [.47,0.67,0.19]; %button changes to green;
            app.FigureSavedLabel.Visible = true;
        end

        % Menu selected function: TraceOnlyMenu
        function TraceOnlyMenuSelected(app, event)
            app.TraceOnlyMenu.Checked = 1;
            app.ShowSpikeDetectionMenu.Checked = 0;
        end

        % Menu selected function: ShowSpikeDetectionMenu
        function ShowSpikeDetectionMenuSelected(app, event)
            app.ShowSpikeDetectionMenu.Checked = 1;
            app.TraceOnlyMenu.Checked = 0;
        end

        % Menu selected function: spikeDetector_SKMenu
        function spikeDetector_SKMenuSelected(app, event)
            app.spikeDetector_SKMenu.Checked = 1;
            app.simpleSpikeDetector_SHMenu.Checked = 0;
        end

        % Menu selected function: simpleSpikeDetector_SHMenu
        function simpleSpikeDetector_SHMenuSelected(app, event)
            app.simpleSpikeDetector_SHMenu.Checked = 1;
            app.spikeDetector_SKMenu.Checked = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dataViewerUI = uifigure('Visible', 'off');
            app.dataViewerUI.Name = 'UI Figure';
            app.AnalysisOptionsMenu = uimenu(app.dataViewerUI);
            app.AnalysisOptionsMenu.Text = 'Analysis Options';
            app.SpikeDetectionMenu = uimenu(app.AnalysisOptionsMenu);
            app.SpikeDetectionMenu.Text = 'Spike Detection';
            app.spikeDetector_SKMenu = uimenu(app.SpikeDetectionMenu);
            app.spikeDetector_SKMenu.MenuSelectedFcn = createCallbackFcn(app, @spikeDetector_SKMenuSelected, true);
            app.spikeDetector_SKMenu.Checked = 'on';
            app.spikeDetector_SKMenu.Text = 'spikeDetector_SK';
            app.simpleSpikeDetector_SHMenu = uimenu(app.SpikeDetectionMenu);
            app.simpleSpikeDetector_SHMenu.MenuSelectedFcn = createCallbackFcn(app, @simpleSpikeDetector_SHMenuSelected, true);
            app.simpleSpikeDetector_SHMenu.Text = 'simpleSpikeDetector_SH';
            app.UITable = uitable(app.dataViewerUI);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.CellIDDataViewerLabel = uilabel(app.dataViewerUI);
            app.CellIDDataViewerLabel.Text = 'CellID - Data Viewer';
            app.RunAnalysisButton = uibutton(app.dataViewerUI, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.RecordingTypeDropDownLabel = uilabel(app.dataViewerUI);
            app.RecordingTypeDropDownLabel.Text = 'Recording Type';
            app.RecordingTypeDropDown = uidropdown(app.dataViewerUI);
            app.RecordingTypeDropDown.Items = {'Extracellular', 'Excitation (Intracellular)', 'Inhibition (Intracellular)'};
            app.RecordingTypeDropDown.Value = 'Extracellular';
            app.ViewDetailsButton = uibutton(app.dataViewerUI, 'push');
            app.ViewDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDetailsButtonPushed, true);
            app.ViewDetailsButton.Text = 'View Details';
            app.EpochEditFieldLabel = uilabel(app.dataViewerUI);
            app.EpochEditFieldLabel.Text = 'Epoch #';
            app.EpochEditField = uieditfield(app.dataViewerUI, 'text');
            app.PlotRawDataButton = uibutton(app.dataViewerUI, 'push');
            app.PlotRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRawDataButtonPushed, true);
            app.PlotRawDataButton.Text = 'Plot Raw Data';
            app.WillAnalyzeEditFieldLabel = uilabel(app.dataViewerUI);
            app.WillAnalyzeEditFieldLabel.Text = 'Will Analyze';
            app.WillAnalyzeEditField = uieditfield(app.dataViewerUI, 'text');
            app.ViewSingleEpochsLabel = uilabel(app.dataViewerUI);
            app.ViewSingleEpochsLabel.Text = 'View Single Epochs';
            app.SetAnalysisParametersLabel = uilabel(app.dataViewerUI);
            app.SetAnalysisParametersLabel.Text = 'Set Analysis Parameters';
            app.AnalysisTypeDropDownLabel = uilabel(app.dataViewerUI);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.dataViewerUI);
            app.AnalysisTypeDropDown.Items = {'Flash', 'Bar Noise', 'Moving Bar', 'Moving Grating Direction', 'Moving Grating Speed', 'Moving Grating Spatial Frequency, '};
            app.AnalysisTypeDropDown.Value = 'Flash';
            app.SaveButton = uibutton(app.dataViewerUI, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.savemessageLabel = uilabel(app.dataViewerUI);
            app.savemessageLabel.Visible = 'off';
            app.savemessageLabel.Text = 'save message';
            app.RevertButton = uibutton(app.dataViewerUI, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Visible = 'off';
            app.RevertButton.Tooltip = {'Revert to last saved data'};
            app.RevertButton.Text = 'Revert';
            app.SuffixEditFieldLabel = uilabel(app.dataViewerUI);
            app.SuffixEditFieldLabel.Text = 'Suffix';
            app.SuffixEditField = uieditfield(app.dataViewerUI, 'text');
            app.SaveFigureButton = uibutton(app.dataViewerUI, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Visible = 'off';
            app.SaveFigureButton.Text = 'Save Figure';
            app.FigureSavedLabel = uilabel(app.dataViewerUI);
            app.FigureSavedLabel.Visible = 'off';
            app.FigureSavedLabel.Text = 'Figure Saved!';
            app.AnalysisTagsListBoxLabel = uilabel(app.dataViewerUI);
            app.AnalysisTagsListBoxLabel.Text = 'Analysis Tags';
            app.AnalysisTagsListBox = uilistbox(app.dataViewerUI);
            app.AnalysisTagsListBox.Items = {'Preliminary Data', 'Phasic', 'Tonic', 'MTN'};
            app.AnalysisTagsListBox.Multiselect = 'on';
            app.AnalysisTagsListBox.Tooltip = {'Set analysis tags for this cell. Tags are saved once for the cell, not per analysis.You may select multiple tags by holding down the cntrl/cmnd button. '};
            app.AnalysisTagsListBox.Value = {};
            app.CellTypeButtonGroup = uibuttongroup(app.dataViewerUI);
            app.CellTypeButtonGroup.Title = 'Cell Type';
            app.ONButton = uiradiobutton(app.CellTypeButtonGroup);
            app.ONButton.Text = 'ON';
            app.ONButton.Value = true;
            app.ONOFFButton = uiradiobutton(app.CellTypeButtonGroup);
            app.ONOFFButton.Text = 'ON-OFF';
            app.OFFButton = uiradiobutton(app.CellTypeButtonGroup);
            app.OFFButton.Text = 'OFF';
            app.UpdateCellListSwitchLabel = uilabel(app.dataViewerUI);
            app.UpdateCellListSwitchLabel.Text = 'Update Cell List';
            app.UpdateCellListSwitch = uiswitch(app.dataViewerUI, 'slider');
            app.UpdateCellListSwitch.Value = 'On';
            app.ContextMenu = uicontextmenu(app.dataViewerUI);
            % Assign app.ContextMenu
            app.PlotRawDataButton.ContextMenu = app.ContextMenu;
            app.PlotOptionsMenu = uimenu(app.ContextMenu);
            app.PlotOptionsMenu.Text = 'Plot Options';
            app.TraceOnlyMenu = uimenu(app.PlotOptionsMenu);
            app.TraceOnlyMenu.MenuSelectedFcn = createCallbackFcn(app, @TraceOnlyMenuSelected, true);
            app.TraceOnlyMenu.Checked = 'on';
            app.TraceOnlyMenu.Text = 'Trace Only';
            app.ShowSpikeDetectionMenu = uimenu(app.PlotOptionsMenu);
            app.ShowSpikeDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowSpikeDetectionMenuSelected, true);
            app.ShowSpikeDetectionMenu.Tooltip = {'Check to show spike detection in raw trace'};
            app.ShowSpikeDetectionMenu.Text = 'Show Spike Detection';
            % Show the figure after all components are created
            app.dataViewerUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataViewerApp_OKR_Physiology(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dataViewerUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dataViewerUI)
        end
    end
end