classdef dataViewerApp_OKR_Physiology_ObjAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        dataViewerUI                matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        PathMenu                    matlab.ui.container.Menu
        SelectCellListMenu          matlab.ui.container.Menu
        cellList_OKRMenu            matlab.ui.container.Menu
        cellList_OKR2PMenu          matlab.ui.container.Menu
        UpdateCellListMenu          matlab.ui.container.Menu
        OpenNotesMenu               matlab.ui.container.Menu
        AnalysisMenu                matlab.ui.container.Menu
        SpikeDetectionMenu          matlab.ui.container.Menu
        spikeDetector_SKMenu        matlab.ui.container.Menu
        simpleSpikeDetector_SHMenu  matlab.ui.container.Menu
        trendsSpikeDetector_SHMenu  matlab.ui.container.Menu
        localSpikeDetector_SHMenu   matlab.ui.container.Menu
        PipetteSolutionMenu         matlab.ui.container.Menu
        CesiumMenu                  matlab.ui.container.Menu
        PotassiumMenu               matlab.ui.container.Menu
        HEPEsMenu                   matlab.ui.container.Menu
        CueMethodsMenu              matlab.ui.container.Menu
        AddPairMenu                 matlab.ui.container.Menu
        SetFiringRateWindowMenu     matlab.ui.container.Menu
        SetRigMenu                  matlab.ui.container.Menu
        ConditionsMenu              matlab.ui.container.Menu
        SelectConditionsMenu        matlab.ui.container.Menu
        ViewDescriptionsMenu        matlab.ui.container.Menu
        AddNewConditionMenu         matlab.ui.container.Menu
        SaveFigureButton            matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        RevertButton                matlab.ui.control.Button
        RunAnalysisButton           matlab.ui.control.Button
        SuffixEditFieldLabel        matlab.ui.control.Label
        SuffixEditField             matlab.ui.control.EditField
        CellTypeButtonGroup         matlab.ui.container.ButtonGroup
        ONButton                    matlab.ui.control.RadioButton
        ONOFFButton                 matlab.ui.control.RadioButton
        OFFButton                   matlab.ui.control.RadioButton
        CellIDDataViewerLabel       matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        AnalysisTagsListBoxLabel    matlab.ui.control.Label
        AnalysisTagsListBox         matlab.ui.control.ListBox
        ViewEpochsLabel             matlab.ui.control.Label
        EpochEditFieldLabel         matlab.ui.control.Label
        EpochEditField              matlab.ui.control.EditField
        ViewDetailsButton           matlab.ui.control.Button
        PlotRawDataButton           matlab.ui.control.Button
        RecordingTypeDropDownLabel  matlab.ui.control.Label
        RecordingTypeDropDown       matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel   matlab.ui.control.Label
        AnalysisTypeDropDown        matlab.ui.control.DropDown
        WillAnalyzeEditFieldLabel   matlab.ui.control.Label
        WillAnalyzeEditField        matlab.ui.control.EditField
        SetAnalysisParametersLabel  matlab.ui.control.Label
        DataSavedLabel              matlab.ui.control.Label
        FigureSavedLabel            matlab.ui.control.Label
        AnalysisCueTextAreaLabel    matlab.ui.control.Label
        AnalysisCueTextArea         matlab.ui.control.TextArea
        AVOIDCheckBox               matlab.ui.control.CheckBox
        FillButton                  matlab.ui.control.Button
        SolutionUnspecifiedLabel    matlab.ui.control.Label
        ConditionsLabel             matlab.ui.control.Label
        IDButton                    matlab.ui.control.Button
        ContextMenu                 matlab.ui.container.ContextMenu
        PlotOptionsMenu             matlab.ui.container.Menu
        ShowSpikeDetectionMenu      matlab.ui.container.Menu
        CalculateMaxFiringRateForSingleEpochMenu  matlab.ui.container.Menu
        RunAnalysisCueForSingleEpochMenu  matlab.ui.container.Menu
        ConditionsContextMenu       matlab.ui.container.ContextMenu
        ClearMenu                   matlab.ui.container.Menu
    end

    
    properties (Access = public)
        cellID %cellID input argument (string)
        originalData %data input argument
        CellParameters %.mat structure that is either loaded from path or created and saved to proper location
    end
    
    properties (Access = private)
        analysisFigure % figure handle for most recent figure produced by analysis
        lastAnalysisType %name of the last analysisType done (used in saving figure)
        lastRecordingTypeAndSuffix %name of last recording type and suffix (used in saving figure)
        cellListFName % Specifies name of which cell list you are saving data to. There are multiple since the original got too big
        cellList % list of all cells that have been analyzed before... this will be updated by the app as the current cell's data is saved.
        cellListPath %path of cell list
        analysesPerformed %cell array that lists analyses done since last save and/or revert. Each list item is a cell array in itself where the first element is str of the recording type and the analysisType and the second element is the recordingTypeAndSuffix
        lastAnalysisObject % analysisObject returned by an analysis
        maxFiringRateWindowSize = 50 %sliding window length for max firing rate calculations
        recordingRig = 'E'; %name of the rig that the experiment was run on
        conditionIndices = []; %holds the indices of currently selected conditions
        conditionsFilePath = 'C:\Users\mrsco\Box\DataAndAnalysis\labData\OKR\physiology\matlabCode\Info\Conditions.txt'; %relative path to the conditions text file
        stimulusIDFolderPath = 'C:\Users\mrsco\Box\DataAndAnalysis\labData\OKR\physiology\matlabCode\stimulusIDs'; %path to folder containing stimulus IDs
        stimulusIDStruct %information about stimulusIDs;
    end
    
    
    methods (Access = private) %helper functions
        
        function fillCellParameters(app, processedDataStruct)
            %Here we add the analysis that was just done to the
            %CellParameters structure, which contains all analyses done for
            %this cell so far.
            
            %Two field names give the analysis specificity: the first is
            %the "analysis type" (e.g. flash, moving grating) and the
            %second is the "recording type" (e.g. extracellular). These names
            %are the first and second branches of processedDataStruct,
            %respectively, and we'd like to preserve them. However, we can
            %save multiple repetitions of an analysis seperately if a
            %suffix has been speficied by the user. The suffix will be
            %added on to the "recording type" field name (e.g.
            %extracellular_2).
            justAnalyzed = processedDataStruct; %simple rename of data struct to add
            %get analysis and recording types, along with the suffix if it
            %exists
            fieldToAdd1 = fieldnames(justAnalyzed);
            analysisType = fieldToAdd1{1};
            fieldToAdd2 = fieldnames(justAnalyzed.(analysisType));
            recordingType = fieldToAdd2{1};
            recordingTypeAndSuffix = strcat(recordingType, app.SuffixEditField.Value);
            
            %Ask if the analysis type + recording type has been analyzed
            %before
            if isfield(app.CellParameters, 'Analyses_Completed')...
                    && isfield(app.CellParameters.Analyses_Completed, analysisType)...
                    && isfield(app.CellParameters.Analyses_Completed.(analysisType), recordingTypeAndSuffix)
            %Notify user if their duplicating a branch of the
            %structure. Permanent overwriting will happen if they save,
            %but they still have the option of "reverting" after
            %pressing okay
            
            waitfor(msgbox(['This analysis has been performed. Previously saved data will' ...
                ' be permamently overwritten unless the "Revert" button is pressed.'], 'Overwrite Warning'))
            end
            
            %fill cell parameters
            app.CellParameters.Analyses_Completed.(analysisType).(recordingTypeAndSuffix).Analysis_Results =...
                justAnalyzed.(analysisType).(recordingType);
            app.CellParameters.Analyses_Completed.(analysisType).(recordingTypeAndSuffix).meta  =...
                justAnalyzed.(analysisType).(recordingType).meta;
            app.CellParameters.Cell_Type.Physiology = justAnalyzed.cellType;
            
            %add avoid tag to the specific analysis if needed
            app.CellParameters.Analyses_Completed.(analysisType).(recordingTypeAndSuffix).meta.AVOID = app.AVOIDCheckBox.Value;
            
            %add pipette solution if needed, add it to the meta data
            solution = 'Unspecified';
            if app.HEPEsMenu.Checked
                solution = 'HEPEs';
            elseif app.CesiumMenu.Checked
                solution = 'Cesium';
            elseif app.PotassiumMenu.Checked
                solution = 'Potassium';
            end
            app.CellParameters.Analyses_Completed.(analysisType).(recordingTypeAndSuffix).meta.pipetteSolution = solution;


            %give user ability to save the data structure
            app.SaveButton.Visible = true;
            app.SaveButton.Enable = true;
            
            %temporarily save some data about the last analysis done
            %(SaveFigureButtonPushed)
            app.lastAnalysisType = analysisType;
            app.lastRecordingTypeAndSuffix = recordingTypeAndSuffix;
        end


        function updateCellList(app)
            %cellList is an Nx2 cell array. Column 1 contains the names of
            %all the cells that have been analyzed. Column 2 contains a
            %structure for that cell with information about it
            
            %load seperately on each save so you get the most up to date
            %version. If you load cell list in startup then multiple cells
            %being analayzed at once will have old copies of the file
            
            %As of 9/3/2022 there are multiple cellLists because once they
            %get too big the save function slows down. The desired cell
            %list can be selected in file --> Select Cell List
            [~, cellIndex] = app.checkCellList(strcat(app.cellListPath, app.cellListFName), 1);
            
            %add all the analyses listed in app.analysesPerformed to the
            %cellList
            if cellIndex == 0 %cell was found in cellList
                cellIndex = size(app.cellList, 1)+1;
            end

            cellRecordStruct = app.CellParameters;
            
            cellRecordStruct.updateHTML = 1; %tag used by python to know who to update
            
            app.cellList{cellIndex, 1} = app.cellID;
            app.cellList{cellIndex, 2} = cellRecordStruct;
            
            cellList = app.cellList;
            save(strcat(app.cellListPath, app.cellListFName), "cellList")
            
            clear cellList
            
            %clear app.analysesPerformed, it will be refilled if subsequent
            %analyses are run
            app.analysesPerformed = {};
        end
        
        function [found, cellIndex] = checkCellList(app, fName, addCellList)
            found = 0; cellIndex = 0;
            load(fName); %loads variable with name "cellList" to the workspace (Nx2 cell array, column 1 = strings of cell names, column 2 = structs for each cell saying if it has been analyzed).
            if addCellList
                app.cellList = cellList;
            end
            cL = cellList;
            clear cellList
            
            for i = 1:size(cL, 1)
                if strcmp(app.cellID, cL{i, 1})
                    cellIndex = i;
                    break
                end
            end
            if cellIndex > 0
                found = 1;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, cell_id, cellListPath)
            %Inputs are: data = data structure formed by master script
            %cell_id = string corresponding to cell's name as it appears as
            %a field in the data structure (e.g. 'SH13Lc1')
            app.CellIDDataViewerLabel.Text = [cell_id ' - Data Viewer'];
            expGroup_id = data.(cell_id).epochLog(1,:);
            startStopIndex = data.(cell_id).epochLog(2, :);
            d = cell(numel(expGroup_id), 6); %initialize table
            
            %build cell array d w/ data that you'll want to present in the
            %gui (UITable)
            for i = 1:numel(expGroup_id)
                d{i, 1} = false;
                d{i, 2} = expGroup_id{i};
                startIndex = startStopIndex{i}(1);%start index
                stopIndex = startStopIndex{i}(2);%stop index
                numEpochs = stopIndex-startIndex + 1;
                try
                    onlineAnalysisType = data.(cell_id).epochs(startIndex).meta.onlineAnalysis;
                catch
                    onlineAnalysisType = 'unknown';
                end
                d{i, 3} = numEpochs;
                d{i, 4} = startIndex;
                d{i, 5} = stopIndex;
                d{i, 6} = onlineAnalysisType;
            end
            
            app.UITable.Data = d; %fill UITable with data for this cell
            app.UITable.ColumnName = {'Analyze', 'Protocol', '# Epochs', 'Start Index',...
                'Stop Index', 'Online Analysis'};
            app.UITable.ColumnEditable = true;
            
            app.cellID = cell_id;
            app.originalData = data;
            
            app.EpochEditField.Value = 'View an Epoch #';
            
            % Check for existence of CellParameters structure in file
            % location. If it doesn't exist initialize it, otherwise just
            % load it.
            cp_path = strcat(data.(cell_id).fileLocation, cell_id, '\', cell_id, '_Analysis.mat');
            if exist(cp_path) == 2
                load(cp_path); %loads to workspace as variable called "CellParameters"
                app.CellParameters = CellParameters;
                app.CellParameters.fileLocation = strcat(data.(cell_id).fileLocation, cell_id, '\');
                app.CellParameters.coordinates = data.(cell_id).coordinates;
                
                %if the cell parameters file exists already, you're going
                %to want to check whether the current cell is also already
                %in a cellList batch file. Load them and check for the
                %current cell's name
                if app.checkCellList(strcat(app.cellListPath, 'cellList_OKR.mat'), 1)
                    app.cellList_OKRMenuSelected();
                elseif app.checkCellList(strcat(app.cellListPath, 'cellList_OKR2P.mat'), 1)
                    app.cellList_OKR2PMenuSelected();
                else
                    %default case, feel free to change. User can also
                    %change at any
                    app.cellList_OKR2PMenuSelected();
                end
                
                
            else
                cp = struct();
                cp.cellID = cell_id;
                cp.fileLocation = strcat(data.(cell_id).fileLocation, cell_id, '\');
                cp.coordinates = data.(cell_id).coordinates;
                
                app.CellParameters = cp;
                app.cellList_OKR2PMenuSelected();
            end
            
            %get analysis tags if they exist
            preexistingTags = [];
            app.AnalysisTagsListBox.Multiselect = 1;
            if isfield(app.CellParameters, 'Tags')
                for j = 1:numel(app.CellParameters.Tags)
                    t = app.CellParameters.Tags{j};
                    for k = 1:numel(app.AnalysisTagsListBox.Items)
                        available = app.AnalysisTagsListBox.Items{k};
                        if strcmp(t, available)
                            preexistingTags = [preexistingTags k];
                        end
                    end
                end
            end
            app.AnalysisTagsListBox.Value = app.AnalysisTagsListBox.Items(preexistingTags);
            
            % Load the list of all cells and save it as a property.
            app.cellListPath = cellListPath;
            app.analysesPerformed = {};
            
            %load the stimulusID information
            load(fullfile(app.stimulusIDFolderPath, 'stimulusIDs.mat'))
            app.stimulusIDStruct = stimulusIDs;
        end

        % Button pushed function: RunAnalysisButton
        function RunAnalysisButtonPushed(app, event)
            
            %!!!!-----------------------------------------------------------------!!!
            %Use class defs for each analysis type to run an analysis.
            %There is a method in the classdef called analysis that will
            %execute the analysis function. This move to a bit of object
            %oriented analysis is the primary change between this and the
            %original version of the app
            analysisType = app.AnalysisTypeDropDown.Value;
            recordingType = app.RecordingTypeDropDown.Value;
            a = eval([analysisType(analysisType ~= ' '), '_OKR_Physiology_ObjAnalysis']); %initialize analysis object - name will be without spaces
            
            a.data = app.originalData;
            a.recordingType = recordingType;
            a.recordingRig = app.recordingRig;
            a.epochsSelected = str2num(app.WillAnalyzeEditField.Value);
            a.cellID = app.cellID;
            a.cellType = app.CellTypeButtonGroup.SelectedObject.Text;
            a.analysisType = analysisType;
            
            %add option to add conditions from conditions menu
            
            if app.spikeDetector_SKMenu.Checked
                a.spikeDetector = 'SpikeDetector_SK';
            elseif app.simpleSpikeDetector_SHMenu.Checked
                a.spikeDetector = 'simpleSpikeDetector_SH';
            elseif app.trendsSpikeDetector_SHMenu.Checked
                a.spikeDetector = 'trendsSpikeDetector_SH';
            elseif app.localSpikeDetector_SHMenu.Checked
                a.spikeDetector = 'localSpikeDetector_SH';
            end
            
            a.maxFiringRateWindow = app.maxFiringRateWindowSize;
            %first run cued analyses
            customCode = app.AnalysisCueTextArea.Value; %text area becomes line by line cell array
            for i = 1:numel(customCode) %evaluates line by line
                eval(customCode{i});
            end
            
            %run main analysis script
            tic
            disp(['Beginning Analysis For ' analysisType])
            a = a.analysis; %analysis method from the object
            disp(['Elapsed Analysis Time: ' num2str(toc) 's'])
            
            processedDataStruct = a.processedData;
            if isempty(fieldnames(processedDataStruct)) %make sure the analysis was actually done. If it doesn't exist then processedDataStruct should be empty
                warndlg('Could not perform analysis as requested. Ensure that there is an analysis script associated with this stimulus and recording type')
                disp('Warning: Could not perform analysis as requested. Ensure that there is an analysis script associated with this stimulus and recording type')
                return
            end
            processedDataStruct.cellType = a.cellType; %must add celltype AFTER everything else that goes into processedDataStruct
            
            app.lastAnalysisObject = a;
            
            %processedDataStruct is a structure containing the single
            %analysis that you just performed.
            %!!!-------------------------------------------------------------------!!!
            
            
            %add the new analaysis to CellParameters, which is a larger
            %structure containing all the analyses that have been done for
            %this cell.
            app.fillCellParameters(processedDataStruct);
            
            %update list of latest recording types run
            app.analysesPerformed{end + 1, 1} = {app.lastAnalysisType, app.lastRecordingTypeAndSuffix};
            
            %update buttons and show saved statuses
            app.SaveFigureButton.Visible = true;
            app.SaveFigureButton.FontColor = [1, 0, 0];
            app.FigureSavedLabel.Visible = false;
            app.DataSavedLabel.Visible = false;
            app.SaveButton.FontColor = [1, 0, 0];
            app.RevertButton.Visible = true;
            
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            currentSelectedIndices = event.Indices;
            rowToSee = currentSelectedIndices(1);
            startIndexOfRowToSee = app.UITable.Data(rowToSee, 4);
            app.EpochEditField.Value = num2str(startIndexOfRowToSee{1});
        end

        % Button pushed function: ViewDetailsButton
        function ViewDetailsButtonPushed(app, event)
            openvar(['data.' app.cellID '.epochs(' app.EpochEditField.Value ').meta'])
        end

        % Button pushed function: PlotRawDataButton
        function PlotRawDataButtonPushed(app, event)
            epochs = str2num(app.EpochEditField.Value);
            for i = 1:numel(epochs)
                epoch_i = epochs(i);
                trace = app.originalData.(app.cellID).epochs(epoch_i).epoch(:)';
                meta = app.originalData.(app.cellID).epochs(epoch_i).meta;
                try
                    totalTime = meta.preTime + meta.stimTime + meta.tailTime;
                catch
                    try
                        totalTime = meta.preTime + meta.currentStimTime + meta.tailTime;
                    catch
                        totalTime = numel(trace)/10;
                    end
                end
                
                time = linspace(0, totalTime, numel(trace));
                
                
                if app.RunAnalysisCueForSingleEpochMenu.Checked
                    a = analysisSuperClass;
                    a.data = app.originalData;
                    a.cellID = app.cellID;
                    a.epochsSelected = epoch_i;
                    customCode = app.AnalysisCueTextArea.Value; %text area becomes line by line cell array
                    for i = 1:numel(customCode) %evaluates line by line
                        eval(customCode{i});
                    end
                    trace = a.data.(a.cellID).epochs(i).epoch;
                end
                
                if isfield(meta, 'currentStimTime')
                    meta.stimTime = meta.currentStimTime;
                end
                
                %Executes only when you want to see the raw trace alone
                figure()
                hold on
                plot(time, trace)
                title([app.cellID ' Epoch #'  num2str(epoch_i) ' - raw trace'])
                try
                    plot([meta.preTime meta.preTime+meta.stimTime],...
                        [mean(trace) + 12*std(trace), mean(trace) + 12*std(trace)],...
                        'Color', 'k', 'LineWidth', 2)
                catch
                end
                xlabel('time')
                
                if ~app.ShowSpikeDetectionMenu.Checked
                    hold off
                    continue %stop here if you don't want to see spikes
                end
                
                %Executes only when you want to see the spike detection on the
                %raw trace
                if app.spikeDetector_SKMenu.Checked
                    spikeData = SpikeDetector_SK(trace);
                elseif app.simpleSpikeDetector_SHMenu.Checked
                    spikeData = simpleSpikeDetector_SH(trace);
                elseif app.trendsSpikeDetector_SHMenu.Checked
                    spikeData = trendsSpikeDetector_SH(trace);
                elseif app.localSpikeDetector_SHMenu.Checked
                    spikeData = localSpikeDetector_SH(trace);
                end
                
                spikeTimes = spikeData.sp./10; %convert to ms from sample rate by dividing by 10
                yPos = mean(trace)+ 8*std(trace) .* ones(1, numel(spikeTimes));
                scatter(spikeTimes, yPos, 'dk')
                hold off
                
                if app.CalculateMaxFiringRateForSingleEpochMenu.Checked
                    a = analysisSuperClass;
                    maxFiringRate = a.maxFiringRate(spikeTimes);
                    disp(['Maximum Firing Rate for epoch #' num2str(epoch_i) ' is ', num2str(maxFiringRate), 'hz'])
                end
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            
            epochsToAnalyze = [];
            
            %Make a guess as to what type of analysis will be run
            analysisGuess = app.UITable.Data{1, 2};
            if ismember(analysisGuess, app.AnalysisTypeDropDown.Items)
                app.AnalysisTypeDropDown.Value = analysisGuess;
            end
            
            if indices(2) == 1 %first column edits only
                for i = 1:size(app.UITable.Data, 1)
                    if app.UITable.Data{i, 1} == true
                        analysisGuess = app.UITable.Data{i, 2};
                        %Make a guess as to what type of analysis will be run
                        if ismember(analysisGuess, app.AnalysisTypeDropDown.Items)
                            app.AnalysisTypeDropDown.Value = analysisGuess;
                        end
                        
                        %update epochs to analyze field
                        start = app.UITable.Data{i,4};
                        stop = app.UITable.Data{i, 5};
                        epochsToAnalyze = [epochsToAnalyze start:stop];
                    end
                end
                if ~isempty(epochsToAnalyze)
                    app.WillAnalyzeEditField.Value = num2str(epochsToAnalyze);
                else
                    app.WillAnalyzeEditField.Value = ' ';
                end
                
            end
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            %resets cell parameters to the last saved version... you can
            %never revert further back than the last time you saved.
            cp_path = strcat(app.originalData.(app.cellID).fileLocation, app.cellID, '\', app.cellID, '_Analysis.mat');
            if exist(cp_path) == 2
                load(cp_path);
                app.CellParameters = CellParameters;
            else
                cp = struct();
                cp.cellID = app.cellID;
                cp.fileLocation = strcat(app.originalData.(app.cellID).fileLocation, app.cellID, '\');
                cp.coordinates = app.originalData.(app.cellID).coordinates;
                app.CellParameters = cp;
            end
            app.SaveButton.Visible = false; %show unsaved
            app.SaveFigureButton.Visible = false;
            app.RevertButton.Visible = false;
            
            %reset analysesPerformed, which helps update cellList with the
            %latest analyses run since last save.
            app.analysesPerformed = {};
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %find the proper save location
            saveLocation = app.CellParameters.fileLocation; %This is the normal value for saveLocation (i.e. the default)
            
            %Add analysis Tags and Physiology Cell Type
            app.CellParameters.Tags = app.AnalysisTagsListBox.Value;
            
            %check if the subfolder exists for this cell, if not create it
            if exist(saveLocation)~=7
                mkdir(saveLocation);
            end
            
            fname = strcat(saveLocation, app.cellID, '_Analysis.mat');
            CellParameters = app.CellParameters; %change from app property to stand alone variable for save function
            save(fname, 'CellParameters') %save a .mat file containing structure "CellParameters"
            
            
            if app.UpdateCellListMenu.Checked
                app.updateCellList(); %update the cellList to note a new save for this cell.
            else
                disp('Cell list batch updates are turned off...')
            end
            
            app.SaveButton.FontColor = [.47,0.67,0.19]; %button changes to green
            app.DataSavedLabel.Text = strcat('Success! Saved analyzed data');
            app.DataSavedLabel.Visible = true;
            
            app.RevertButton.Visible = false; %take away revert button because reverting will just bring back the copy that was last saved... i.e. this one
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            
            %find the proper save location
            saveLocation = app.CellParameters.fileLocation; %This is the normal value for saveLocation (i.e. the default)
            
            %check if the subfolder exists for this cell, if not create it.
            if exist(saveLocation)~=7
                mkdir(saveLocation);
            end
            
            %save the figure to the correct folder by referencing its
            %handle (which has been temporarily saved as a property)
            for i = 1:numel(app.lastAnalysisObject.analysisFigures)
                fig = app.lastAnalysisObject.analysisFigures(i);
                extension = app.lastAnalysisObject.analysisFigureExtensions{i};
                fname = strcat(saveLocation, app.lastAnalysisType, '_',app.lastRecordingTypeAndSuffix, extension, '.svg');
                saveas(fig, fname);
            end


            app.SaveFigureButton.FontColor = [.47,0.67,0.19]; %button changes to green;
            app.FigureSavedLabel.Visible = true;
        end

        % Callback function
        function TraceOnlyMenuSelected(app, event)
            app.TraceOnlyMenu.Checked = 1;
            app.ShowSpikeDetectionMenu.Checked = 0;
        end

        % Menu selected function: ShowSpikeDetectionMenu
        function ShowSpikeDetectionMenuSelected(app, event)
            app.ShowSpikeDetectionMenu.Checked = abs(1 - app.ShowSpikeDetectionMenu.Checked); %clever way of switching back and forth between 0 and 1
        end

        % Menu selected function: spikeDetector_SKMenu
        function spikeDetector_SKMenuSelected(app, event)
            app.spikeDetector_SKMenu.Checked = 1;
            app.simpleSpikeDetector_SHMenu.Checked = 0;
            app.trendsSpikeDetector_SHMenu.Checked = 0;
            app.localSpikeDetector_SHMenu.Checked = 0;
        end

        % Menu selected function: simpleSpikeDetector_SHMenu
        function simpleSpikeDetector_SHMenuSelected(app, event)
            app.simpleSpikeDetector_SHMenu.Checked = 1;
            app.spikeDetector_SKMenu.Checked = 0;
            app.trendsSpikeDetector_SHMenu.Checked = 0;
            app.localSpikeDetector_SHMenu.Checked = 0;
        end

        % Menu selected function: trendsSpikeDetector_SHMenu
        function trendsSpikeDetector_SHMenuSelected(app, event)
            app.spikeDetector_SKMenu.Checked = 0;
            app.simpleSpikeDetector_SHMenu.Checked = 0;
            app.trendsSpikeDetector_SHMenu.Checked = 1;
            app.localSpikeDetector_SHMenu.Checked = 0;
        end

        % Menu selected function: localSpikeDetector_SHMenu
        function localSpikeDetector_SHMenuSelected(app, event)
            app.spikeDetector_SKMenu.Checked = 0;
            app.simpleSpikeDetector_SHMenu.Checked = 0;
            app.trendsSpikeDetector_SHMenu.Checked = 0;
            app.localSpikeDetector_SHMenu.Checked = 1;
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            app.SaveButtonPushed;
            disp('Save Successful at '); disp(datetime)
        end

        % Menu selected function: PathMenu
        function PathMenuSelected(app, event)
            selpath = uigetdir(app.CellParameters.fileLocation, 'Select Path to Save Location');
            app.CellParameters.fileLocation = [selpath, '\'];
        end

        % Menu selected function: UpdateCellListMenu
        function UpdateCellListMenuSelected(app, event)
            app.UpdateCellListMenu.Checked = abs(1 - app.UpdateCellListMenu.Checked); %clever way of switching back and forth between 0 and 1
        end

        % Menu selected function: RunAnalysisCueForSingleEpochMenu
        function RunAnalysisCueForSingleEpochMenuSelected(app, event)
            app.RunAnalysisCueForSingleEpochMenu.Checked = abs(1 - app.RunAnalysisCueForSingleEpochMenu.Checked); %clever way of switching back and forth between 0 and 1
        end

        % Menu selected function: CueMethodsMenu
        function CueMethodsMenuSelected(app, event)
            a = analysisSuperClass;
            msgbox(methods(a), 'Available Methods For Analysis Cue')
        end

        % Menu selected function: SetFiringRateWindowMenu
        function SetFiringRateWindowMenuSelected(app, event)
            userInput = inputdlg('Enter sliding window value for max firing rate (in ms):', 'Maximum Firing Rate Window', [1 40], {'50'});
            val = str2num(userInput{1});
            app.maxFiringRateWindowSize = val;
        end

        % Menu selected function: 
        % CalculateMaxFiringRateForSingleEpochMenu
        function CalculateMaxFiringRateForSingleEpochMenuSelected(app, event)
            app.CalculateMaxFiringRateForSingleEpochMenu.Checked = abs(1 - app.RunAnalysisCueForSingleEpochMenu.Checked); %clever way of switching back and forth between 0 and 1
        end

        % Menu selected function: AddPairMenu
        function AddPairMenuSelected(app, event)
            pair = uigetfile('*.mat', 'Select paired cells', 'MultiSelect', 'off');
            if isfield(app.CellParameters, 'PairedCells')
                app.CellParameters.PairedCells{end+1} = {pair(1:strfind(pair, '_')-1)};
            else
                app.CellParameters.PairedCells = {pair(1:strfind(pair, '_')-1)};
            end
            msgbox('View command window for pairs')
            disp(app.CellParameters.PairedCells)
        end

        % Menu selected function: SetRigMenu
        function SetRigMenuSelected(app, event)
            rigs = {'E', 'F'};
            newID = listdlg('PromptString', 'Select the recording rig', 'ListString', rigs);
            app.recordingRig = rigs{newID};
        end

        % Button pushed function: FillButton
        function FillButtonPushed(app, event)
            app.EpochEditField.Value = app.WillAnalyzeEditField.Value;
        end

        % Menu selected function: CesiumMenu
        function CesiumMenuSelected(app, event)
            app.CesiumMenu.Checked = abs(1 - app.CesiumMenu.Checked); %clever way of switching back and forth between 0 and 1
            app.PotassiumMenu.Checked = 0;
            app.HEPEsMenu.Checked = 0;
            app.SolutionUnspecifiedLabel.Visible = 1;
            app.SolutionUnspecifiedLabel.Text = 'Solution: Cesium';
        end

        % Menu selected function: PotassiumMenu
        function PotassiumMenuSelected(app, event)
            app.PotassiumMenu.Checked = abs(1 - app.PotassiumMenu.Checked); %clever way of switching back and forth between 0 and 1
            app.CesiumMenu.Checked = 0;
            app.HEPEsMenu.Checked = 0;
            app.SolutionUnspecifiedLabel.Visible = 1;
            app.SolutionUnspecifiedLabel.Text = 'Solution: Potassium';
        end

        % Menu selected function: HEPEsMenu
        function HEPEsMenuSelected(app, event)
            app.HEPEsMenu.Checked = abs(1 - app.HEPEsMenu.Checked); %clever way of switching back and forth between 0 and 1
            app.CesiumMenu.Checked = 0;
            app.PotassiumMenu.Checked = 0;
            app.SolutionUnspecifiedLabel.Visible = 1;
            app.SolutionUnspecifiedLabel.Text = 'Solution: HEPEs';
        end

        % Value changed function: RecordingTypeDropDown
        function RecordingTypeDropDownValueChanged(app, event)
            value = app.RecordingTypeDropDown.Value;
            if contains(value, '(Intracellular)') && app.HEPEsMenu.Checked
                warndlg('Pipette Solution is set to HEPEs. Change in Analysis/Pipette Solution menu')
            end
            
            if contains(value, 'Extracellular') && (app.CesiumMenu.Checked || app.PotassiumMenu.Checked)
                warndlg('Pipette solution is set to Cesium or Potassium. Change in Analysis/Pipette Solution menu')
            end
            
            if contains(value, 'Cesium') && ~app.CesiumMenu.Checked
                app.CesiumMenuSelected;
                warndlg('Automatically changed pipette solution to Cesium. Manually change back in Analysis/Pipette Soultion menu')
            end
            
            if (contains(value, 'Potassium') || contains(value, 'K+')) && ~app.PotassiumMenu.Checked
                app.PotassiumMenuSelected;
                warndlg('Automatically changed pipette solution to Potasium. Manually change back in Analysis/Pipette Soultion menu')
            end
        end

        % Menu selected function: SelectConditionsMenu
        function SelectConditionsMenuSelected(app, event)
            %This function draws available conditions from Conditions.txt
            %file. It then displays these conditions in a list box. The
            %user selects which conditions they would like to apply to the
            %current analysis. A string summarizing the selected conditions
            %then fills the suffix box (where further customization is
            %available if desired). Importantly, conditions only get tacked
            %onto experiment descriptions VIA the suffix box. If it doesn't
            %make it to the suffix box, it won't make it into the saved
            %data!
            
            
            f = fopen(app.conditionsFilePath); %open the file
            
            text = fscanf(f, '%c'); %read the file to a character string
            
            conditions = findMarkerInText(text, '#'); %look for all available condition names
            [selection, tf] = listdlg('ListString', conditions, 'InitialValue', app.conditionIndices);
            if ~tf %end the function if the user hits cancel
                return
            end
            selectionString = '';
            for i = 1:numel(selection)
                selection_i = conditions{selection(i)};
                switch selection_i
                    case 'Clear Selected Conditions'
                        selectionString = '';
                        selection = [];
                        break
                end
                selectionString = [selectionString, '_', selection_i];
            end
            
            %add the condition to the suffix edit field
            app.SuffixEditField.Value = [app.SuffixEditField.Value, selectionString];
            
            %update selected indices for the future
            app.ConditionsLabel.Text = ['Conditions: ' num2str(numel(selection))];
            
            app.conditionIndices = selection;
            
            fclose(f);
            
            drawnow;
            figure(app.dataViewerUI)
        end

        % Menu selected function: ViewDescriptionsMenu
        function ViewDescriptionsMenuSelected(app, event)
            f = fopen(app.conditionsFilePath); %open the file
            
            text = fscanf(f, '%c'); %read the file to a character string
            
            conditions = findMarkerInText(text, '#'); %look for all available condition names
            descriptions = findMarkerInText(text, '^');
            disp(newline);
            disp('Available Conditions:')
            for i = 1:numel(conditions)
                displayString = [conditions{i} ': ' descriptions{i}];
                disp(displayString)
            end
            msgbox('View Command Window for Condition Descriptions')
            fclose(f);
        end

        % Menu selected function: AddNewConditionMenu
        function AddNewConditionMenuSelected(app, event)
            %CREATE THIS CALLBACK WHEN YOU HAVE A CHANCE SO THAT THE USER
            %DOESNT NEED TO EDIT THE CONDITIONS TEXT FILE DIRECTLY IN ORDER
            %TO ADD A NEW CONDITION
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            %clears selected conditions
            selection = [];
            app.ConditionsLabel.Text = ['Conditions: ' num2str(numel(selection))];
            app.conditionIndices = selection;
        end

        % Menu selected function: OpenNotesMenu
        function OpenNotesMenuSelected(app, event)
            %Open the notes file for this recording
            fpath = app.CellParameters.fileLocation;
            slashes = strfind(fpath, '\');
            notesPath = fpath(1:slashes(end-1));
            recordingDate = fpath(slashes(end-2)+1:slashes(end-1)-1);
            notesFname = [notesPath, recordingDate '_notes.txt'];
            try
                system(['"' notesFname '"&']);
            catch
                errordlg('Could not locate or open the notes file. Open manually')
            end
        end

        % Menu selected function: cellList_OKRMenu
        function cellList_OKRMenuSelected(app, event)
            app.cellList_OKRMenu.Checked = 1;
            app.cellList_OKR2PMenu.Checked = 0;
            app.cellListFName = 'cellList_OKR.mat';
        end

        % Menu selected function: cellList_OKR2PMenu
        function cellList_OKR2PMenuSelected(app, event)
            app.cellList_OKRMenu.Checked = 0;
            app.cellList_OKR2PMenu.Checked = 1;
            app.cellListFName = 'cellList_OKR2P.mat';
        end

        % Button pushed function: IDButton
        function IDButtonPushed(app, event)
            % Determine the stimulus ID of the selected epoch
            epochNumber = str2double(app.EpochEditField.Value);
            metaStruct = app.originalData.(app.cellID).epochs(epochNumber).meta;
            [~, stimulusID] = findStimulusID(app.stimulusIDStruct, metaStruct);
            app.SuffixEditField.Value = ['_', stimulusID];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.dataViewerUI = uifigure('Visible', 'off');
            app.dataViewerUI.Name = 'UI Figure';
            app.FileMenu = uimenu(app.dataViewerUI);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.PathMenu = uimenu(app.FileMenu);
            app.PathMenu.MenuSelectedFcn = createCallbackFcn(app, @PathMenuSelected, true);
            app.PathMenu.Text = 'Path';
            app.SelectCellListMenu = uimenu(app.FileMenu);
            app.SelectCellListMenu.Text = 'Select Cell List';
            app.cellList_OKRMenu = uimenu(app.SelectCellListMenu);
            app.cellList_OKRMenu.MenuSelectedFcn = createCallbackFcn(app, @cellList_OKRMenuSelected, true);
            app.cellList_OKRMenu.Text = 'cellList_OKR';
            app.cellList_OKR2PMenu = uimenu(app.SelectCellListMenu);
            app.cellList_OKR2PMenu.MenuSelectedFcn = createCallbackFcn(app, @cellList_OKR2PMenuSelected, true);
            app.cellList_OKR2PMenu.Text = 'cellList_OKR2P';
            app.UpdateCellListMenu = uimenu(app.FileMenu);
            app.UpdateCellListMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateCellListMenuSelected, true);
            app.UpdateCellListMenu.Tooltip = {'If checked, saving data to Cell List'};
            app.UpdateCellListMenu.Checked = 'on';
            app.UpdateCellListMenu.Text = 'Update Cell List';
            app.OpenNotesMenu = uimenu(app.FileMenu);
            app.OpenNotesMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenNotesMenuSelected, true);
            app.OpenNotesMenu.Text = 'Open Notes';
            app.AnalysisMenu = uimenu(app.dataViewerUI);
            app.AnalysisMenu.Text = 'Analysis';
            app.SpikeDetectionMenu = uimenu(app.AnalysisMenu);
            app.SpikeDetectionMenu.Text = 'Spike Detection';
            app.spikeDetector_SKMenu = uimenu(app.SpikeDetectionMenu);
            app.spikeDetector_SKMenu.MenuSelectedFcn = createCallbackFcn(app, @spikeDetector_SKMenuSelected, true);
            app.spikeDetector_SKMenu.Text = 'spikeDetector_SK';
            app.simpleSpikeDetector_SHMenu = uimenu(app.SpikeDetectionMenu);
            app.simpleSpikeDetector_SHMenu.MenuSelectedFcn = createCallbackFcn(app, @simpleSpikeDetector_SHMenuSelected, true);
            app.simpleSpikeDetector_SHMenu.Text = 'simpleSpikeDetector_SH';
            app.trendsSpikeDetector_SHMenu = uimenu(app.SpikeDetectionMenu);
            app.trendsSpikeDetector_SHMenu.MenuSelectedFcn = createCallbackFcn(app, @trendsSpikeDetector_SHMenuSelected, true);
            app.trendsSpikeDetector_SHMenu.Text = 'trendsSpikeDetector_SH';
            app.localSpikeDetector_SHMenu = uimenu(app.SpikeDetectionMenu);
            app.localSpikeDetector_SHMenu.MenuSelectedFcn = createCallbackFcn(app, @localSpikeDetector_SHMenuSelected, true);
            app.localSpikeDetector_SHMenu.Checked = 'on';
            app.localSpikeDetector_SHMenu.Text = 'localSpikeDetector_SH';
            app.PipetteSolutionMenu = uimenu(app.AnalysisMenu);
            app.PipetteSolutionMenu.Text = 'Pipette Solution';
            app.CesiumMenu = uimenu(app.PipetteSolutionMenu);
            app.CesiumMenu.MenuSelectedFcn = createCallbackFcn(app, @CesiumMenuSelected, true);
            app.CesiumMenu.Text = 'Cesium';
            app.PotassiumMenu = uimenu(app.PipetteSolutionMenu);
            app.PotassiumMenu.MenuSelectedFcn = createCallbackFcn(app, @PotassiumMenuSelected, true);
            app.PotassiumMenu.Text = 'Potassium';
            app.HEPEsMenu = uimenu(app.PipetteSolutionMenu);
            app.HEPEsMenu.MenuSelectedFcn = createCallbackFcn(app, @HEPEsMenuSelected, true);
            app.HEPEsMenu.Text = 'HEPEs';
            app.CueMethodsMenu = uimenu(app.AnalysisMenu);
            app.CueMethodsMenu.MenuSelectedFcn = createCallbackFcn(app, @CueMethodsMenuSelected, true);
            app.CueMethodsMenu.Text = 'Cue Methods';
            app.AddPairMenu = uimenu(app.AnalysisMenu);
            app.AddPairMenu.MenuSelectedFcn = createCallbackFcn(app, @AddPairMenuSelected, true);
            app.AddPairMenu.Text = 'Add Pair';
            app.SetFiringRateWindowMenu = uimenu(app.AnalysisMenu);
            app.SetFiringRateWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @SetFiringRateWindowMenuSelected, true);
            app.SetFiringRateWindowMenu.Text = 'Set Firing Rate Window';
            app.SetRigMenu = uimenu(app.AnalysisMenu);
            app.SetRigMenu.MenuSelectedFcn = createCallbackFcn(app, @SetRigMenuSelected, true);
            app.SetRigMenu.Text = 'Set Rig';
            app.ConditionsMenu = uimenu(app.dataViewerUI);
            app.ConditionsMenu.Text = 'Conditions';
            app.SelectConditionsMenu = uimenu(app.ConditionsMenu);
            app.SelectConditionsMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectConditionsMenuSelected, true);
            app.SelectConditionsMenu.Text = 'Select Conditions';
            app.ViewDescriptionsMenu = uimenu(app.ConditionsMenu);
            app.ViewDescriptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewDescriptionsMenuSelected, true);
            app.ViewDescriptionsMenu.Text = 'View Descriptions';
            app.AddNewConditionMenu = uimenu(app.ConditionsMenu);
            app.AddNewConditionMenu.MenuSelectedFcn = createCallbackFcn(app, @AddNewConditionMenuSelected, true);
            app.AddNewConditionMenu.Text = 'Add New Condition';
            app.SaveFigureButton = uibutton(app.dataViewerUI, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Visible = 'off';
            app.SaveFigureButton.Text = 'Save Figure';
            app.SaveButton = uibutton(app.dataViewerUI, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.RevertButton = uibutton(app.dataViewerUI, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Visible = 'off';
            app.RevertButton.Tooltip = {'Revert to last saved data'};
            app.RevertButton.Text = 'Revert';
            app.RunAnalysisButton = uibutton(app.dataViewerUI, 'push');
            app.RunAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @RunAnalysisButtonPushed, true);
            app.RunAnalysisButton.Text = 'Run Analysis';
            app.SuffixEditFieldLabel = uilabel(app.dataViewerUI);
            app.SuffixEditFieldLabel.Text = 'Suffix';
            app.SuffixEditField = uieditfield(app.dataViewerUI, 'text');
            app.CellTypeButtonGroup = uibuttongroup(app.dataViewerUI);
            app.CellTypeButtonGroup.Title = 'Cell Type';
            app.ONButton = uiradiobutton(app.CellTypeButtonGroup);
            app.ONButton.Text = 'ON';
            app.ONButton.Value = true;
            app.ONOFFButton = uiradiobutton(app.CellTypeButtonGroup);
            app.ONOFFButton.Text = 'ON-OFF';
            app.OFFButton = uiradiobutton(app.CellTypeButtonGroup);
            app.OFFButton.Text = 'OFF';
            app.CellIDDataViewerLabel = uilabel(app.dataViewerUI);
            app.CellIDDataViewerLabel.Text = 'CellID - Data Viewer';
            app.UITable = uitable(app.dataViewerUI);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.AnalysisTagsListBoxLabel = uilabel(app.dataViewerUI);
            app.AnalysisTagsListBoxLabel.Text = 'Analysis Tags';
            app.AnalysisTagsListBox = uilistbox(app.dataViewerUI);
            app.AnalysisTagsListBox.Items = {'Preliminary Data', 'MTN', 'Avoid For Tuning', 'Mislabeled', 'UP Cell', 'DOWN Cell', 'No Epi', '2 Photon', 'PD Unknown'};
            app.AnalysisTagsListBox.Multiselect = 'on';
            app.AnalysisTagsListBox.Tooltip = {'Set analysis tags for this cell. Tags are saved once for the cell, not per analysis.You may select multiple tags by holding down the cntrl/cmnd button. '};
            app.AnalysisTagsListBox.Value = {};
            app.ViewEpochsLabel = uilabel(app.dataViewerUI);
            app.ViewEpochsLabel.Text = 'View Epochs';
            app.EpochEditFieldLabel = uilabel(app.dataViewerUI);
            app.EpochEditFieldLabel.Text = 'Epoch #';
            app.EpochEditField = uieditfield(app.dataViewerUI, 'text');
            app.ViewDetailsButton = uibutton(app.dataViewerUI, 'push');
            app.ViewDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDetailsButtonPushed, true);
            app.ViewDetailsButton.Text = 'View Details';
            app.PlotRawDataButton = uibutton(app.dataViewerUI, 'push');
            app.PlotRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRawDataButtonPushed, true);
            app.PlotRawDataButton.Text = 'Plot Raw Data';
            app.RecordingTypeDropDownLabel = uilabel(app.dataViewerUI);
            app.RecordingTypeDropDownLabel.Text = 'Recording Type';
            app.RecordingTypeDropDown = uidropdown(app.dataViewerUI);
            app.RecordingTypeDropDown.Items = {'Extracellular', 'Excitation (Intracellular)', 'Inhibition (Intracellular)', 'Current Clamp (K+ Spikes)', 'Current Clamp (Cesium)'};
            app.RecordingTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @RecordingTypeDropDownValueChanged, true);
            app.RecordingTypeDropDown.Value = 'Extracellular';
            app.AnalysisTypeDropDownLabel = uilabel(app.dataViewerUI);
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.dataViewerUI);
            app.AnalysisTypeDropDown.Items = {'Flash', 'Bar Noise', 'Led Pulse Family', 'Mini Post Synaptic Currents', 'Moving Bar', 'Moving Bar Intensities', 'Moving Bar Speed Variable Stim Time', 'Moving Bar Speed Variable Width', 'Moving Grating Direction', 'Moving Grating Speed', 'Moving Grating Spatial Frequency', 'Oscillating Grating', 'Ramp'};
            app.AnalysisTypeDropDown.Value = 'Flash';
            app.WillAnalyzeEditFieldLabel = uilabel(app.dataViewerUI);
            app.WillAnalyzeEditFieldLabel.Text = 'Will Analyze';
            app.WillAnalyzeEditField = uieditfield(app.dataViewerUI, 'text');
            app.SetAnalysisParametersLabel = uilabel(app.dataViewerUI);
            app.SetAnalysisParametersLabel.Text = 'Set Analysis Parameters';
            app.DataSavedLabel = uilabel(app.dataViewerUI);
            app.DataSavedLabel.Visible = 'off';
            app.DataSavedLabel.Text = 'Data Saved';
            app.FigureSavedLabel = uilabel(app.dataViewerUI);
            app.FigureSavedLabel.Visible = 'off';
            app.FigureSavedLabel.Text = 'Figure Saved!';
            app.AnalysisCueTextAreaLabel = uilabel(app.dataViewerUI);
            app.AnalysisCueTextAreaLabel.Text = 'Analysis Cue';
            app.AnalysisCueTextArea = uitextarea(app.dataViewerUI);
            app.AnalysisCueTextArea.Tooltip = {'Custom code should come from superclass methods. Go to Analysis --> Cue Methods in the menubar to view available methods.'; ''; 'Enter analyses as "a = a.function(inputs);"'; ''; 'This does not perminantly change the data, rather it executes only on the current run.'};
            app.AnalysisCueTextArea.Value = {'%Enter custom code from superclass methods'; '%e.g. a = a.lowpassfilter();'};
            app.AVOIDCheckBox = uicheckbox(app.dataViewerUI);
            app.AVOIDCheckBox.Tooltip = {'Check this box if you would like to add an AVOID tag to this analysis.'};
            app.AVOIDCheckBox.Text = 'AVOID';
            app.FillButton = uibutton(app.dataViewerUI, 'push');
            app.FillButton.ButtonPushedFcn = createCallbackFcn(app, @FillButtonPushed, true);
            app.FillButton.Text = 'Fill';
            app.SolutionUnspecifiedLabel = uilabel(app.dataViewerUI);
            app.SolutionUnspecifiedLabel.Text = 'Solution: Unspecified';
            app.ConditionsLabel = uilabel(app.dataViewerUI);
            app.ConditionsLabel.Text = 'Conditions: 0';
            app.IDButton = uibutton(app.dataViewerUI, 'push');
            app.IDButton.ButtonPushedFcn = createCallbackFcn(app, @IDButtonPushed, true);
            app.IDButton.Text = {'ID'; ''};
            app.ContextMenu = uicontextmenu(app.dataViewerUI);
            % Assign app.ContextMenu
            app.PlotRawDataButton.ContextMenu = app.ContextMenu;
            app.PlotOptionsMenu = uimenu(app.ContextMenu);
            app.PlotOptionsMenu.Text = 'Plot Options';
            app.ShowSpikeDetectionMenu = uimenu(app.PlotOptionsMenu);
            app.ShowSpikeDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowSpikeDetectionMenuSelected, true);
            app.ShowSpikeDetectionMenu.Tooltip = {'Check to show spike detection in raw trace'};
            app.ShowSpikeDetectionMenu.Text = 'Show Spike Detection';
            app.CalculateMaxFiringRateForSingleEpochMenu = uimenu(app.PlotOptionsMenu);
            app.CalculateMaxFiringRateForSingleEpochMenu.MenuSelectedFcn = createCallbackFcn(app, @CalculateMaxFiringRateForSingleEpochMenuSelected, true);
            app.CalculateMaxFiringRateForSingleEpochMenu.Text = 'Calculate Max Firing Rate';
            app.RunAnalysisCueForSingleEpochMenu = uimenu(app.PlotOptionsMenu);
            app.RunAnalysisCueForSingleEpochMenu.MenuSelectedFcn = createCallbackFcn(app, @RunAnalysisCueForSingleEpochMenuSelected, true);
            app.RunAnalysisCueForSingleEpochMenu.Text = 'Run Analysis Cue';
            app.ConditionsContextMenu = uicontextmenu(app.dataViewerUI);
            % Assign app.ConditionsContextMenu
            app.ConditionsLabel.ContextMenu = app.ConditionsContextMenu;
            app.ClearMenu = uimenu(app.ConditionsContextMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            % Show the figure after all components are created
            app.dataViewerUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataViewerApp_OKR_Physiology_ObjAnalysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.dataViewerUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.dataViewerUI)
        end
    end
end