classdef miniAnalysisThresholding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        QuitMenu                        matlab.ui.container.Menu
        EvokedResponsesLabel            matlab.ui.control.Label
        PretimeVarianceLabel            matlab.ui.control.Label
        ThresholdLevelEditFieldLabel    matlab.ui.control.Label
        evokedThresholdEditField        matlab.ui.control.NumericEditField
        ThresholdLevelEditFieldLabel_2  matlab.ui.control.Label
        varianceThresholdEditField      matlab.ui.control.NumericEditField
        ConfirmButton                   matlab.ui.control.Button
        CountLabel                      matlab.ui.control.Label
        evokedLineplot                  matlab.ui.control.UIAxes
        varianceLineplot                matlab.ui.control.UIAxes
        evokedHistogram                 matlab.ui.control.UIAxes
        varianceHistogram               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        allEpochNums
        includedEpochNums % Description
        evokedResponses
        variances
        includedResponses % Description
        includedVariances
        currentMaxResponse
    end
    
    properties (Access = public)
        thresholdingResults %output of the app - struct of data
        running = 1; %remains as 1 until the user wants to close the app
    end
    
    methods (Access = private)
        
        function plotData(app)

            %evoked line plot
            cla(app.evokedLineplot);
            hold(app.evokedLineplot, 'on')
            plot(app.evokedLineplot, app.includedEpochNums, app.includedResponses, 'k')
            hold(app.evokedLineplot, 'off')
            
            
            %variance line plot
            cla(app.varianceLineplot);
            hold(app.varianceLineplot, 'on')
            plot(app.varianceLineplot, app.includedEpochNums, app.includedVariances, 'k')
            hold(app.varianceLineplot, 'off')
            
            [~, excludedIndices] = setdiff(app.allEpochNums, app.includedEpochNums);
            
            %evoked histogram
            edgs = [0:0.05:1];
            cla(app.evokedHistogram);
            hold(app.evokedHistogram, 'on')
            histogram(app.evokedHistogram, app.includedResponses, 'FaceColor', 'g', 'BinEdges', edgs)
            histogram(app.evokedHistogram, app.evokedResponses(excludedIndices)./app.currentMaxResponse, 'FaceColor', 'r', 'BinEdges', edgs)
            hold(app.evokedHistogram, 'off')
            
            %variance histogram
            cla(app.varianceHistogram);
            hold(app.varianceHistogram, 'on')
            h1 = histogram(app.varianceHistogram, app.includedVariances, 'FaceColor', 'g');
            h2 = histogram(app.varianceHistogram, app.variances(excludedIndices), 'FaceColor', 'r');
            h2.BinWidth = h1.BinWidth;
            hold(app.varianceHistogram, 'off')
            
            %update text field
            app.CountLabel.Text = [num2str(numel(app.includedEpochNums)) ' of ' ...
                num2str(numel(app.allEpochNums)) ' total epochs meet the current criteria'];
        end
        
        function executeThresholds(app)
            evokedThreshold = app.evokedThresholdEditField.Value;
            varianceThreshold = app.varianceThresholdEditField.Value;
           
            %the filter works as follows: First exclude epochs that are
            %above the variance threshold. Then renormalize the evoked
            %thresholds and exclude epochs that do not exceed the new
            %evoked threshold minimum. Save the epoch numbers, evoked
            %responses, and variances of the epochs that pass both
            %criteria.
            availableIndices = app.variances <= varianceThreshold; %array of 0's and 1's indicating which - out of all epochs - meet the variance threshold
            availableResponses = app.evokedResponses(availableIndices); %remaining evoked responses
            app.currentMaxResponse = max(availableResponses);
            normalizedResponses = availableResponses./app.currentMaxResponse; %renormalize
            remainingResponses = normalizedResponses(normalizedResponses > evokedThreshold); %threshold with the new normalization
            firstPassEpochsLeft = app.allEpochNums(availableIndices); %now get epoch numbers associated with the remaining epochs
            secondPassEpochsLeft = firstPassEpochsLeft(normalizedResponses > evokedThreshold);
            app.includedEpochNums = secondPassEpochsLeft;
            app.includedResponses = remainingResponses;
            availableVariances = app.variances(availableIndices);
            app.includedVariances = availableVariances(normalizedResponses > evokedThreshold);
            app.plotData();
        end
    end
    
    methods (Access = public)
        
        function closeApp(app)
            delete(app); %close the app from outside
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, epochNums, evokedResponses, variances)
            
            app.allEpochNums = epochNums;
            app.includedEpochNums = epochNums;
            
            app.evokedResponses = evokedResponses;
            app.variances = variances;
            app.varianceThresholdEditField.Value = max(variances);
            
            app.executeThresholds;
        end

        % Value changed function: evokedThresholdEditField, 
        % varianceThresholdEditField
        function ThresholdValueChanged(app, event)
            app.executeThresholds;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            %send results of the analysis back to the workspace in a
            %structure called thresholdingResults and then close the app
            thesholdingResults = struct();
            thresholdingResults.usableEpochNumbers = app.includedEpochNums;
            thresholdingResults.pretimeVariances = app.includedVariances;
            thresholdingResults.evokedResponseMagnitudes = app.includedResponses;
            thresholdingResults.varianceThreshold = app.varianceThresholdEditField.Value;
            thresholdingResults.evokedThreshold = app.evokedThresholdEditField.Value;
            app.thresholdingResults = thresholdingResults;
            app.running = 0; %will signal the close of the app
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            app.UIFigureCloseRequest();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.QuitMenu = uimenu(app.UIFigure);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.EvokedResponsesLabel = uilabel(app.UIFigure);
            app.EvokedResponsesLabel.Text = 'Evoked Responses';
            app.PretimeVarianceLabel = uilabel(app.UIFigure);
            app.PretimeVarianceLabel.Text = 'Pretime Variance';
            app.ThresholdLevelEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdLevelEditFieldLabel.Text = 'Threshold Level';
            app.evokedThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.evokedThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueChanged, true);
            app.evokedThresholdEditField.Value = 0.2;
            app.ThresholdLevelEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ThresholdLevelEditFieldLabel_2.Text = 'Threshold Level';
            app.varianceThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.varianceThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdValueChanged, true);
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.CountLabel = uilabel(app.UIFigure);
            app.CountLabel.Text = 'A of B total epochs meet the current criteria ';
            app.evokedLineplot = uiaxes(app.UIFigure);
            xlabel(app.evokedLineplot, 'Epoch Number')
            ylabel(app.evokedLineplot, 'Relative Response')
            zlabel(app.evokedLineplot, 'Z')
            app.varianceLineplot = uiaxes(app.UIFigure);
            xlabel(app.varianceLineplot, 'Epoch Number')
            ylabel(app.varianceLineplot, 'Variance (pA^2)')
            zlabel(app.varianceLineplot, 'Z')
            app.evokedHistogram = uiaxes(app.UIFigure);
            xlabel(app.evokedHistogram, 'Relative Response')
            ylabel(app.evokedHistogram, 'Number Of Epochs')
            zlabel(app.evokedHistogram, 'Z')
            app.varianceHistogram = uiaxes(app.UIFigure);
            xlabel(app.varianceHistogram, 'Variance (pA^2)')
            zlabel(app.varianceHistogram, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = miniAnalysisThresholding(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end