classdef MosaicAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        File                           matlab.ui.container.Menu
        ShowImageMenu                  matlab.ui.container.Menu
        UpdateBatchListMenu            matlab.ui.container.Menu
        RevertMenu                     matlab.ui.container.Menu
        ToOrginalDataMenu              matlab.ui.container.Menu
        ToLastSavedDataMenu            matlab.ui.container.Menu
        OptionsMenu                    matlab.ui.container.Menu
        RunFiji                        matlab.ui.container.Menu
        FindCentersButton              matlab.ui.control.Button
        ScaleLabel                     matlab.ui.control.Label
        CenterParametersLabel          matlab.ui.control.Label
        LoadButton                     matlab.ui.control.Button
        LoadLamp                       matlab.ui.control.Lamp
        LoadLabel                      matlab.ui.control.Label
        LoadedFile                     matlab.ui.control.Label
        ZScoreThresholdEditFieldLabel  matlab.ui.control.Label
        ZScoreThresholdEditField       matlab.ui.control.NumericEditField
        LocalRadiusEditFieldLabel      matlab.ui.control.Label
        LocalRadiusEditField           matlab.ui.control.NumericEditField
        BlankAreaEditFieldLabel        matlab.ui.control.Label
        BlankAreaEditField             matlab.ui.control.NumericEditField
        BlurSizeEditFieldLabel         matlab.ui.control.Label
        BlurSizeEditField              matlab.ui.control.NumericEditField
        MinimumAreaEditFieldLabel      matlab.ui.control.Label
        MinimumAreaEditField           matlab.ui.control.NumericEditField
        PixelsPerMMEditFieldLabel      matlab.ui.control.Label
        PixelsPerMM                    matlab.ui.control.NumericEditField
        SaveLamp                       matlab.ui.control.Lamp
        SaveButton                     matlab.ui.control.Button
        SaveLabel                      matlab.ui.control.Label
        ImageType                      matlab.ui.control.ToggleSwitch
        ImageTypeSwitchLabel           matlab.ui.control.Label
        DrawMaskButton                 matlab.ui.control.Button
        LinkImageButton                matlab.ui.control.Button
        MaskLabel                      matlab.ui.control.Label
        AnalysesLabel                  matlab.ui.control.Label
        NumberofBinsEditFieldLabel     matlab.ui.control.Label
        NumberofBinsEditField          matlab.ui.control.NumericEditField
        RunDensityRecoveryButton       matlab.ui.control.Button
        LabelingEfficacySwitch         matlab.ui.control.Switch
        RunNearestNeighborButton       matlab.ui.control.Button
        EyeSwitch                      matlab.ui.control.Switch
        LabelingInformation            matlab.ui.control.Label
        NearestNeighborComplete        matlab.ui.control.Lamp
        DensityRecoveryComplete        matlab.ui.control.Lamp
        DataInformationKnobLabel       matlab.ui.control.Label
        DataInformationKnob            matlab.ui.control.DiscreteKnob
        EditCentersButton              matlab.ui.control.Button
        AnimalIDEditFieldLabel         matlab.ui.control.Label
        AnimalID                       matlab.ui.control.EditField
        RunNearestNeighborx3Button     matlab.ui.control.Button
        NearestNeighbor3Complete       matlab.ui.control.Lamp
        LandmarksLabel                 matlab.ui.control.Label
        IDCenterNorthPointButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        LoadPath %path to location where image was loaded (also used as save path)
        data = struct(); %Holds previous data if it exists;
        originalData = struct(); %for reverting to the original data that was loaded with the app
        lastSavedData = struct(); %for reverting to the last saved version of the data
        figure %figure of the labeled retina, can have centers and or mask
        image %image of the labeled retina
        centers %nx2 matrix with (x, y) coordinates of cells
        mask %binary mask of retina area
        results %results structure that holds data for both drp and nearest neighbor
        drpFig = []; %Figure handle for density recovery profile
        nnFig = []; %Figure handle for nearest neighbor analysis
        nn3Fig1 = []; %First figure handle for nearest neighbor x3 analysis
        nn3Fig2 = []; %Second figure handle for nearest neighbor x3 analysis
        retinaCenter = []; %Holds coordinates of the center of the retina
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
             %% Load Data
            [fname, path] = uigetfile({'*.png;*.mat','Supported formats (*.png, *.mat)'; '*.h5','Fiji export files (*.png)';'*.mat','Matlab Exports (*.mat)'}, 'Select an image or structure');
            [~, ~, ext] = fileparts([path, fname]);

            switch ext
                case '.png'
                    app.image = imread(fullfile(path, fname));
                    app.LoadLamp.Color = [0, 1, 0];
                    app.LoadedFile.Text = ['Loaded: ', fname];
                    app.FindCentersButton.Enable = 1;
                    
                    app.centers = [];
                    app.mask = [];
                    app.SaveButton.Enable = 0;
                    app.LinkImageButton.Enable = 0;
                    
                    app.AnimalID.Value = fname(1:end-4);
                case '.mat'
                    app.data = load(fullfile(path, fname)).MosaicAnalysisResults;
                    app.originalData = app.data; %used when reverting
                    app.lastSavedData = app.data;
                    
                    %look for previously saved center and mask data
                    fs = fieldnamesr(app.data, 'full');
                    if ismember({[app.EyeSwitch.Value,'.', app.ImageType.Value,'.', app.DataInformationKnob.Value]}, fs)
                        loc = app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                            .(app.DataInformationKnob.Value);
                        
                        f1 = 0;
                        %look for previously saved center data
                        if isfield(loc, 'centers')
                            app.centers = loc.centers;
                            app.EditCentersButton.Enable = 1;
                            app.RunNearestNeighborButton.Enable = 1;
                            app.RunNearestNeighborx3Button.Enable = 1;
                            f1 = 1;
                        else
                            errordlg('Center Data for Selected Analysis Not Found. Manually Select Center Data')
                            pause(3)
                            allFields = fieldnamesr(app.data);
                            [fieldIndex, tf] = listdlg('ListString', allFields,...
                            'Name', 'Select the field', 'ListSize', [600 600], 'SelectionMode', 'single');
                            if tf == 0
                                app.centers = [];
                            
                            else
                                fieldName = allFields{fieldIndex};
                                app.centers = eval(['app.data.', fieldName]);
                                app.EditCentersButton.Enable = 1;
                                app.RunNearestNeighborButton.Enable = 1;
                                app.RunNearestNeighborx3Button.Enable = 1;
                            end
                        end
                        
                        %look for previously saved mask data
                        if isfield(loc, 'mask')
                            app.mask = loc.mask;
                            if f1
                                app.RunDensityRecoveryButton.Enable = 1;
                            end
                        else
                            errordlg('Mask Data for Selected Analysis Not Found. Manually Select Mask Data')
                            pause(3)
                            allFields = fieldnamesr(app.data);
                            [fieldIndex, tf] = listdlg('ListString', allFields,...
                            'Name', 'Select the field', 'ListSize', [600 600], 'SelectionMode', 'single');
                            if tf == 0
                                app.mask = [];
                            else
                                fieldName = allFields{fieldIndex};
                                app.mask = eval(['app.data.', fieldName]);
                            end
                            
                        end
                    end

                app.LoadLamp.Color = [0, 1, 0];
                app.LoadedFile.Text = ['Loaded: ', fname];
                app.LinkImageButton.Visible = 1;
                app.SaveButton.Enable = 0;
                
                app.AnimalID.Value = fname(1:strfind(fname, '_MosaicAnalysisResults.mat')-1);
            
            otherwise
                app.LoadLamp.Color = [1, 1, 0];
                pause(1.0);
                app.LoadLamp.Color = [1, 0, 0];
                app.LoadedFile.Text = 'Could Not Load Data';
            end
            
            app.LoadPath = path;
            
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).FileLocation = app.LoadPath;
            
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: FindCentersButton
        function FindCentersButtonPushed(app, event)
            [app.centers, app.figure] = findCenters_MosaicAnalysis(app.image, app.ZScoreThresholdEditField.Value, app.LocalRadiusEditField.Value,...
                app.BlankAreaEditField.Value, app.BlurSizeEditField.Value, app.MinimumAreaEditField.Value, app.PixelsPerMM.Value);
            
            app.EditCentersButton.Enable = 1;
            app.DrawMaskButton.Enable = 1;
            app.RunNearestNeighborButton.Enable = 1;
            app.RunNearestNeighborx3Button.Enable = 1;
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).centers = app.centers;
        end

        % Button pushed function: DrawMaskButton
        function DrawMaskButtonPushed(app, event)
            %user draws mask
            imshow(app.image);
            hold on
            scatter(app.centers(:, 2), app.centers(:, 1), 'ow')
            
            r = drawfreehand();
            app.mask = r.createMask;
            
            %remove centers that aren't in the mask
            centersInPlay = [];
            for i = 1:size(app.centers, 1)
                center_i = app.centers(i, :);
                if app.mask(round(center_i(1)), round(center_i(2)))
                    centersInPlay = [centersInPlay; center_i];
                end
            end
            app.centers = centersInPlay;
            
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).centers = app.centers;
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).mask = app.mask;
            
            scatter(app.centers(:, 2), app.centers(:, 1), 'g')
            
            app.RunDensityRecoveryButton.Enable = 1;
        end

        % Button pushed function: LinkImageButton
        function LinkImageButtonPushed(app, event)
            [fname, path] = uigetfile('*.png'); %load image of the retina
            app.image = imread(fullfile(path, fname));
            app.FindCentersButton.Enable = 1;
            app.DrawMaskButton.Enable = 1;
            drawnow;
            figure(app.UIFigure)
        end

        % Button pushed function: RunDensityRecoveryButton
        function RunDensityRecoveryButtonPushed(app, event)
            [drpStruct, app.drpFig] = computeDRP_MosaicAnalysis(app.image, app.centers, app.mask, app.PixelsPerMM.Value);
            
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).PixelsPerMM = app.PixelsPerMM.Value;
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).drpResults = drpStruct;
            app.SaveButton.Enable = 1;
            app.SaveLamp.Color = [1 0 0];
            app.DensityRecoveryComplete.Visible = 1;
        end

        % Button pushed function: RunNearestNeighborButton
        function RunNearestNeighborButtonPushed(app, event)
            [nnStruct, app.nnFig] = nearestNeighbor_MosaicAnalysis(app.centers, app.PixelsPerMM.Value);
           
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).PixelsPerMM = app.PixelsPerMM.Value;
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).NearestNeighborResults = nnStruct;
            app.SaveButton.Enable = 1;
            app.SaveLamp.Color = [1 0 0];
            app.NearestNeighborComplete.Visible = 1;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            MosaicAnalysisResults = app.data;
            
            %Add labeling efficacy tag
            MosaicAnalysisResults.(app.EyeSwitch.Value).LabelingEfficacy = app.LabelingEfficacySwitch.Value;
            
            %check whether the animal ID tag exists. If it does, make sure
            %it matches what is in the animal ID box. If it does not match,
            %give the user the option to abort. This is most important when
            %batch updates are on because having the wrong animal ID will
            %place the analysis in the wrong place in the batch analysis.
            AnimalID = app.AnimalID.Value;
            if isfield(MosaicAnalysisResults, 'AnimalID')
                if ~strcmp(AnimalID, MosaicAnalysisResults.AnimalID)
                    fig = uifigure;
                    msg = ['Animal ID Mismatch. Proceeding will overwrite the previous animal ID and possibly force a new batch list entry with ID = ' AnimalID];
                    title = 'Confirm Animal ID';
                    selection = uiconfirm(fig,msg,title,...
                               'Options',{'Continue','Abort'},...
                               'DefaultOption',2,'CancelOption',2);
                    switch selection
                        case 'Abort'
                            return
                    end
                end
            end
            
            MosaicAnalysisResults.AnimalID = AnimalID;
           
            %save the data
            cd(app.LoadPath);
            save([AnimalID '_MosaicAnalysisResults.mat'], 'MosaicAnalysisResults');
            app.lastSavedData = app.data;
            
            %save the figures if they haven't been closed yet
            try
                if ~isempty(app.drpFig)
                    saveas(app.drpFig, [AnimalID '_DRP_' app.DataInformationKnob.Value '_' app.ImageType.Value '.svg'])
                end
                
                if ~isempty(app.nnFig)
                    saveas(app.nnFig, [AnimalID '_NearestNeighbor_' app.DataInformationKnob.Value '_' app.ImageType.Value '.svg'])
                end
                
                if ~isempty(app.nn3Fig1)
                    saveas(app.nn3Fig1, [AnimalID '_FractionTriples_' app.DataInformationKnob.Value '_' app.ImageType.Value '.svg'])
                end
                
                if ~isempty(app.nn3Fig2)
                    saveas(app.nn3Fig2, [AnimalID '_NearestNeighborX3_' app.DataInformationKnob.Value '_' app.ImageType.Value '.svg'])
                end
            catch
                errordlg('Unable to save all figures')
            end


            %% update list for batch analysis if user desires
            if app.UpdateBatchListMenu.Checked
                batchFName = 'C:\Users\mrsco\Box\DataAndAnalysis\labData\OKR\batch\MosaicList_OKR.mat';
                load(batchFName); %loads MosaicList_OKR, which is a cell array, first column = animal ID, second column = structs of analysses
                cellIndex = 0;
                
                %Check if animal has been analyzed before
                for i = 1:size(MosaicList_OKR, 1)
                    if strcmp(AnimalID, MosaicList_OKR{i, 1})
                        cellIndex = i; %save index of animal in the list if it has been analyzed before
                        break
                    end
                end
                
                if cellIndex == 0 %cell has not been analyzed before so set it's index to end+1
                    cellIndex = size(MosaicList_OKR, 1) + 1;
                end
                
                %remove drp data because it is so big. This data will live
                %only in the structure that is saved individually for each
                %animal
                saveToBatchList = MosaicAnalysisResults;
                fields = fieldnamesr(saveToBatchList);
                for i = 1:numel(fields)
                    if contains(fields{i}, 'data.drp')
                        eval(['saveToBatchList.' fields{i}(1:end-4)...
                            '= rmfield(saveToBatchList.' fields{i}(1:end-4) ', "drp");']);
                    end
                end
                
                %update the batch list and save
                MosaicList_OKR{cellIndex, 1} = AnimalID;
                MosaicList_OKR{cellIndex, 2} = saveToBatchList;
                
                save(batchFName, 'MosaicList_OKR');
                clear MosaicList_OKR saveToBatchList fields cellIndex batchFName 
            end
            
            app.SaveLamp.Color = [0 1 0];
            
        end

        % Menu selected function: RunFiji
        function RunFijiSelected(app, event)
            %Allow direct opening of fiji from options menu (in menu bar)
            currentFolder = pwd;
            fijiPath = 'C:\Users\mrsco\Documents\fiji\Fiji.app'; %works on scott's laptop
            
            cd(fijiPath)
            %shell command to open image j
            !ImageJ-win64
            
            cd(currentFolder);
        end

        % Button pushed function: EditCentersButton
        function EditCentersButtonPushed(app, event)
            %edit app.centers after they have been loaded once
            
            app.figure = figure(2);
            imshow(app.image);
            hold on
            scatter(app.centers(:, 2), app.centers(:, 1), 'ow')
            hold off
            
            while 1
                resp = questdlg('Edit Centers?', 'Editor', 'Add', 'Remove', 'Done', 'Add');
                if strcmp(resp, 'Done')
                    break
                end
                
                if strcmp(resp, 'Add')
                    newPoint = drawcrosshair();
                    p = round(newPoint.Position);
            
                    app.centers = [app.centers; p(2), p(1)];
                
                elseif strcmp(resp, 'Remove')
                    rect = drawrectangle();
                    p = round(rect.Position);
                    removing = [];
                    for i = 1:size(app.centers, 1)
                        if app.centers(i, 1) > p(2) && app.centers(i, 1) < p(2) + p(4) &&...
                                app.centers(i, 2) > p(1) && app.centers(i, 2) < p(1) + p(3)
                            removing = [removing, i];
                        end
                    end
                    app.centers(removing, :) = [];
                end
                imshow(app.image)
                hold on
                scatter(app.centers(:, 2), app.centers(:, 1), 'ow')
                hold off
            end
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).centers = app.centers;
        end

        % Menu selected function: ShowImageMenu
        function ShowImageMenuSelected(app, event)
            %make the image reappear through menu bar selection
            app.figure = figure(2);
            imshow(app.image)
            hold on
            scatter(app.centers(:, 2), app.centers(:, 1), 'ow')
            hold off
        end

        % Menu selected function: UpdateBatchListMenu
        function UpdateBatchListMenuSelected(app, event)
            app.UpdateBatchListMenu.Checked = abs(1 - app.UpdateBatchListMenu.Checked); %clever way of switching back and forth between 0 and 1
        end

        % Menu selected function: ToOrginalDataMenu
        function ToOrginalDataMenuSelected(app, event)
            app.data = app.originalData;
            disp('######### REVERTED TO ORIGINAL DATA #########')
        end

        % Menu selected function: ToLastSavedDataMenu
        function ToLastSavedDataMenuSelected(app, event)
            app.data = app.lastSavedData;
            disp('######### REVERTED TO LAST SAVED INSTANCE OF DATA #########')
        end

        % Button pushed function: RunNearestNeighborx3Button
        function RunNearestNeighborx3ButtonPushed(app, event)
            [nn3Struct, app.nn3Fig1, app.nn3Fig2] = threewayNearestNeighbor_MosaicAnalysis(app.centers, app.PixelsPerMM.Value);
          
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).PixelsPerMM = app.PixelsPerMM.Value;
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).NearestNeighbor3Results = nn3Struct;
            app.SaveButton.Enable = 1;
            app.SaveLamp.Color = [1 0 0];
            app.NearestNeighbor3Complete.Visible = 1;
        end

        % Button pushed function: IDCenterNorthPointButton
        function IDCenterNorthPointButtonPushed(app, event)
            imshow(app.image);
            
            retinaCenter = drawcrosshair();
            p = round(retinaCenter.Position);
            
            app.retinaCenter = [p(2), p(1)];
            imshow(app.image)
            hold on
            scatter(p(1), p(2), 'g', 'filled')
            hold off
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).retinaCenter = app.retinaCenter;
            
            
            northPoint = drawcrosshair();
            p2 = round(northPoint.Position);
            imshow(app.image)
            hold on
            plot([p(1), p2(1)], [p(2), p2(2)], 'Color', [0, 1, 1], 'LineWidth', 3)
            scatter(p(1), p(2), 'g', 'filled')
            scatter(p2(1), p2(2), 'b', 'filled')
            
            
            app.data.(app.EyeSwitch.Value).(app.ImageType.Value)...
                .(app.DataInformationKnob.Value).northPoint = [p2(2), p2(1)];
            
            app.SaveButton.Enable = 1;
            app.SaveLamp.Color = [1 0 0];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.ShowImageMenu = uimenu(app.File);
            app.ShowImageMenu.MenuSelectedFcn = createCallbackFcn(app, @ShowImageMenuSelected, true);
            app.ShowImageMenu.Text = 'Show Image';
            app.UpdateBatchListMenu = uimenu(app.File);
            app.UpdateBatchListMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateBatchListMenuSelected, true);
            app.UpdateBatchListMenu.Checked = 'on';
            app.UpdateBatchListMenu.Text = 'Update Batch List';
            app.RevertMenu = uimenu(app.File);
            app.RevertMenu.Text = 'Revert';
            app.ToOrginalDataMenu = uimenu(app.RevertMenu);
            app.ToOrginalDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ToOrginalDataMenuSelected, true);
            app.ToOrginalDataMenu.Text = 'To Orginal Data';
            app.ToLastSavedDataMenu = uimenu(app.RevertMenu);
            app.ToLastSavedDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ToLastSavedDataMenuSelected, true);
            app.ToLastSavedDataMenu.Text = 'To Last Saved Data';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.RunFiji = uimenu(app.OptionsMenu);
            app.RunFiji.MenuSelectedFcn = createCallbackFcn(app, @RunFijiSelected, true);
            app.RunFiji.Text = 'Run Fiji';
            app.FindCentersButton = uibutton(app.UIFigure, 'push');
            app.FindCentersButton.ButtonPushedFcn = createCallbackFcn(app, @FindCentersButtonPushed, true);
            app.FindCentersButton.Enable = 'off';
            app.FindCentersButton.Text = 'Find Centers';
            app.ScaleLabel = uilabel(app.UIFigure);
            app.ScaleLabel.Text = 'Scale';
            app.CenterParametersLabel = uilabel(app.UIFigure);
            app.CenterParametersLabel.Text = 'Center Parameters';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.LoadLamp = uilamp(app.UIFigure);
            app.LoadLabel = uilabel(app.UIFigure);
            app.LoadLabel.Text = 'Load';
            app.LoadedFile = uilabel(app.UIFigure);
            app.LoadedFile.Text = '';
            app.ZScoreThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ZScoreThresholdEditFieldLabel.Text = 'Z Score Threshold';
            app.ZScoreThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZScoreThresholdEditField.Tooltip = {'Z score thresholding value for identifying cell in local area'};
            app.ZScoreThresholdEditField.Value = 1.7;
            app.LocalRadiusEditFieldLabel = uilabel(app.UIFigure);
            app.LocalRadiusEditFieldLabel.Text = 'Local Radius';
            app.LocalRadiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.LocalRadiusEditField.Tooltip = {'Defines the area for local averaging (um)'};
            app.LocalRadiusEditField.Value = 40;
            app.BlankAreaEditFieldLabel = uilabel(app.UIFigure);
            app.BlankAreaEditFieldLabel.Text = 'Blank Area';
            app.BlankAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlankAreaEditField.Tooltip = {'Contrast enhancement during local background calculation. Should approximate normal cell size (um^2)'};
            app.BlankAreaEditField.Value = 25;
            app.BlurSizeEditFieldLabel = uilabel(app.UIFigure);
            app.BlurSizeEditFieldLabel.Text = 'Blur Size';
            app.BlurSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlurSizeEditField.Tooltip = {'Gaussian blur kernel size. Set to 1 for no blur (px)'};
            app.BlurSizeEditField.Value = 2;
            app.MinimumAreaEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumAreaEditFieldLabel.Text = 'Minimum Area';
            app.MinimumAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumAreaEditField.Tooltip = {'Minimum area that will qualify as a cell (um^2)'};
            app.MinimumAreaEditField.Value = 30;
            app.PixelsPerMMEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsPerMMEditFieldLabel.Text = 'Pixels Per MM';
            app.PixelsPerMM = uieditfield(app.UIFigure, 'numeric');
            app.PixelsPerMM.Tooltip = {'Scale of the image, pixels per mm'};
            app.PixelsPerMM.Value = 230;
            app.SaveLamp = uilamp(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.SaveLabel = uilabel(app.UIFigure);
            app.SaveLabel.Text = 'Save';
            app.ImageType = uiswitch(app.UIFigure, 'toggle');
            app.ImageType.Items = {'Wide_Field', 'Confocal'};
            app.ImageType.Value = 'Wide_Field';
            app.ImageTypeSwitchLabel = uilabel(app.UIFigure);
            app.ImageTypeSwitchLabel.Text = 'Image Type';
            app.DrawMaskButton = uibutton(app.UIFigure, 'push');
            app.DrawMaskButton.ButtonPushedFcn = createCallbackFcn(app, @DrawMaskButtonPushed, true);
            app.DrawMaskButton.Enable = 'off';
            app.DrawMaskButton.Text = 'Draw Mask';
            app.LinkImageButton = uibutton(app.UIFigure, 'push');
            app.LinkImageButton.ButtonPushedFcn = createCallbackFcn(app, @LinkImageButtonPushed, true);
            app.LinkImageButton.Visible = 'off';
            app.LinkImageButton.Text = 'Link Image';
            app.MaskLabel = uilabel(app.UIFigure);
            app.MaskLabel.Text = 'Mask';
            app.AnalysesLabel = uilabel(app.UIFigure);
            app.AnalysesLabel.Text = 'Analyses';
            app.NumberofBinsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofBinsEditField.Value = 100;
            app.RunDensityRecoveryButton = uibutton(app.UIFigure, 'push');
            app.RunDensityRecoveryButton.ButtonPushedFcn = createCallbackFcn(app, @RunDensityRecoveryButtonPushed, true);
            app.RunDensityRecoveryButton.Enable = 'off';
            app.RunDensityRecoveryButton.Text = 'Run Density Recovery';
            app.LabelingEfficacySwitch = uiswitch(app.UIFigure, 'slider');
            app.LabelingEfficacySwitch.Items = {'Full', 'Partial'};
            app.LabelingEfficacySwitch.Value = 'Full';
            app.RunNearestNeighborButton = uibutton(app.UIFigure, 'push');
            app.RunNearestNeighborButton.ButtonPushedFcn = createCallbackFcn(app, @RunNearestNeighborButtonPushed, true);
            app.RunNearestNeighborButton.Enable = 'off';
            app.RunNearestNeighborButton.Text = 'Run Nearest Neighbor';
            app.EyeSwitch = uiswitch(app.UIFigure, 'slider');
            app.EyeSwitch.Items = {'Contralateral', 'Ipsilatral'};
            app.EyeSwitch.Value = 'Contralateral';
            app.LabelingInformation = uilabel(app.UIFigure);
            app.LabelingInformation.Text = 'Labeling Information';
            app.NearestNeighborComplete = uilamp(app.UIFigure);
            app.NearestNeighborComplete.Visible = 'off';
            app.DensityRecoveryComplete = uilamp(app.UIFigure);
            app.DensityRecoveryComplete.Visible = 'off';
            app.DataInformationKnobLabel = uilabel(app.UIFigure);
            app.DataInformationKnobLabel.Text = 'Data Information';
            app.DataInformationKnob = uiknob(app.UIFigure, 'discrete');
            app.DataInformationKnob.Items = {'Full_Retina', 'Dorsal_Temporal', 'Dorsal_Nasal', 'Ventral_Nasal', 'Ventral_Temporal'};
            app.DataInformationKnob.Value = 'Full_Retina';
            app.EditCentersButton = uibutton(app.UIFigure, 'push');
            app.EditCentersButton.ButtonPushedFcn = createCallbackFcn(app, @EditCentersButtonPushed, true);
            app.EditCentersButton.Enable = 'off';
            app.EditCentersButton.Text = 'Edit Centers';
            app.AnimalIDEditFieldLabel = uilabel(app.UIFigure);
            app.AnimalIDEditFieldLabel.Text = 'Animal ID';
            app.AnimalID = uieditfield(app.UIFigure, 'text');
            app.AnimalID.Value = 'Animal ID';
            app.RunNearestNeighborx3Button = uibutton(app.UIFigure, 'push');
            app.RunNearestNeighborx3Button.ButtonPushedFcn = createCallbackFcn(app, @RunNearestNeighborx3ButtonPushed, true);
            app.RunNearestNeighborx3Button.Enable = 'off';
            app.RunNearestNeighborx3Button.Text = 'Run Nearest Neighbor x3';
            app.NearestNeighbor3Complete = uilamp(app.UIFigure);
            app.NearestNeighbor3Complete.Visible = 'off';
            app.LandmarksLabel = uilabel(app.UIFigure);
            app.LandmarksLabel.Text = 'Landmarks';
            app.IDCenterNorthPointButton = uibutton(app.UIFigure, 'push');
            app.IDCenterNorthPointButton.ButtonPushedFcn = createCallbackFcn(app, @IDCenterNorthPointButtonPushed, true);
            app.IDCenterNorthPointButton.Text = 'ID Center/North Point';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MosaicAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end